classdef MetadataUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AppGrid                       matlab.ui.container.GridLayout
        MetadataGrid                  matlab.ui.container.GridLayout
        DocLinkLabel                  matlab.ui.control.Label
        DocLink                       matlab.ui.control.EditField
        Description                   matlab.ui.control.EditField
        FilePath                      matlab.ui.control.Label
        TaskClassDefinitionFileLabel  matlab.ui.control.Label
        Keywords                      matlab.ui.control.EditField
        KeywordsLabel                 matlab.ui.control.Label
        DescriptionLabel              matlab.ui.control.Label
        BrowseIconButton              matlab.ui.control.Button
        Icon                          matlab.ui.control.Image
        Name                          matlab.ui.control.EditField
        IconLabel                     matlab.ui.control.Label
        NameLabel                     matlab.ui.control.Label
        TaskDetailsLabel              matlab.ui.control.Label
        ActionButtonGrid              matlab.ui.container.GridLayout
        HelpButton                    matlab.ui.control.Button
        OkButton                      matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
    end

    properties (Access = private)
        ViewModel               % reference to MetadataUIViewModel object
        Metadata                % struct holding metadata for the task
        ProgressDialog          % reference to ProgressDialog object
        PathDialogShown = false
    end

    methods (Access = public)

        function localize(app)
            % internationalize: localize all user facing strings
            % this function iterates over all child components of the figure,
            % components that require localization have a their Tag
            % property set in the format <messageCatalogId>:<child's property>
            import matlab.internal.task.metadata.Constants
            childComponents = [app.UIFigure; app.MetadataGrid.Children; app.ActionButtonGrid.Children];
            for taskIndex = 1:length(childComponents)
                childComponent = childComponents(taskIndex);
                if ~isempty(childComponent.Tag)
                    tagParts = strsplit(childComponent.Tag, ':');
                    childComponent.(tagParts{2}) = string(message([Constants.MessageCatalogPrefix, tagParts{1}]));
                end
                if isprop(childComponent, 'Tooltip') && ~isempty(childComponent.Tooltip) && ...
                        (~iscell(childComponent.Tooltip) || ~isempty(childComponent.Tooltip{1}))
                    if iscell(childComponent.Tooltip)
                        childComponent.Tooltip = childComponent.Tooltip{1};
                    end
                    childComponent.Tooltip = string(message([Constants.MessageCatalogPrefix, childComponent.Tooltip]));
                end
            end
        end

        function formattedFilePathTooltip = formatFilePathTooltip(app, filePathTooltip)
            % formattedFilePathTooltip: this funtion adds spaces to the
            % a long filePath-tooltip so that the tooltip wraps around
            % instead of getting clipped by the figure dimension
            import matlab.internal.task.metadata.Constants
            formattedFilePathTooltip = filePathTooltip;
            lineLength = Constants.MaxFilePathLength / 2;
            if length(filePathTooltip) > lineLength
                formattedFilePathTooltip = '';
                index = 1;
                while index < length(filePathTooltip)
                    if index + lineLength > length(filePathTooltip)
                        formattedFilePathTooltip = [formattedFilePathTooltip,' ' ,filePathTooltip(index:end)];
                        break;
                    end
                    filePathPart = filePathTooltip(index:index+lineLength);
                    formattedFilePathTooltip = [formattedFilePathTooltip,' ' ,filePathPart];
                    index = index + lineLength + 1;
                end
            end
        end

        function showRegistrationError(app, ~, eventData)
            % showRegistrationError: this funtions listens to the
            % RegistrationError event and shows appropriate error in the
            % Metadata UI
            import matlab.internal.task.metadata.Constants
            delete(app.ProgressDialog);
            selectionDialogHeader = string(message([Constants.MessageCatalogPrefix, 'TaskRegistrationHeader']));
            selectionDialogMsg = string(message([Constants.MessageCatalogPrefix, 'RegistrationErrorMsg'], eventData.ErrorMessage));
            uialert(app.UIFigure, selectionDialogMsg, selectionDialogHeader,...
                'Icon','error');
        end

        function closeMetadataUI(app, ~, ~)
            % closeMetadataUI: this funtion listens to the
            % RegistrationSuccessEvent and shows the success message in the
            % Metadata UI
            import matlab.internal.task.metadata.Constants
            % close progress indicator
            delete(app.ProgressDialog);

            % construct and show success message
            selectionDialogHeader = string(message([Constants.MessageCatalogPrefix, 'TaskRegistrationHeader']));
            directory = app.ViewModel.getDirectory();
            onPath = app.checkIfFolderOnPath(directory);
            if strcmp(app.ViewModel.getStatus(), Constants.NotRegistered)
                if onPath
                    selectionDialogMsg = string(message([Constants.MessageCatalogPrefix, 'RegistrationSuccessMsgOnPath']));
                else
                    selectionDialogMsg = string(message([Constants.MessageCatalogPrefix, 'RegistrationSuccessMsg']));
                end
            else
                if onPath
                    selectionDialogMsg = string(message([Constants.MessageCatalogPrefix, 'UpdateSuccessMsgOnPath']));
                else
                    selectionDialogMsg = string(message([Constants.MessageCatalogPrefix, 'UpdateSuccessMsg']));
                end
            end

            uialert(app.UIFigure, selectionDialogMsg, selectionDialogHeader,...
                'Icon','success','CloseFcn',@app.cleanUpApp);
        end

        function handleInvalidModel(app)
            % handleInvalidModel: this function shows the error message to
            % the author that the existing liveTasks.json file is
            % invalid and saving metadata for the current task will
            % re-write the existing file
            import matlab.internal.task.metadata.Constants
            selectionDialogHeader = string(message([Constants.MessageCatalogPrefix, 'RegistrationErrorMsg']));
            selectionDialogMsg = string(message([Constants.MessageCatalogPrefix, 'InvalidModelWarningMsg']));

            uialert(app.UIFigure, selectionDialogMsg, selectionDialogHeader,...
                'Icon','warning');
        end

        function resetName(app, ~, ~)
            % resetName:resets value of component name to previous
            % valid value
            app.Name.Value = app.Metadata.name;
        end

        function cleanUpApp(app, ~, ~)
            % cleanUpApp: calls cleanUpApp function on viewmodel and then
            % deletes the app
            app.ViewModel.cleanUpApp();
            delete(app);
        end
    end

    methods (Access = private)
        function onPath = checkIfFolderOnPath(app, directory)
            pathCell = regexp(path, pathsep, 'split');
            if ispc  % Windows is not case-sensitive
                onPath = any(strcmpi(directory, pathCell));
            else
                onPath = any(strcmp(directory, pathCell));
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, viewModel)
            import matlab.internal.task.metadata.Constants

            viewModel.alignFigure(app.UIFigure);

            app.localize();

            % populate UI fields in the app
            [app.FilePath.Text, filePathTooltip] = viewModel.getFilePath();
            app.FilePath.Tooltip = app.formatFilePathTooltip(filePathTooltip);
            metadata = viewModel.getMetadata();
            app.Name.Value = metadata.name;

            dirPath = strjoin(Constants.UserTaskPackagePath, filesep);

            if strcmp(viewModel.getStatus(), Constants.NotRegistered)
                app.Icon.ImageSource = metadata.icon;
            elseif ~isfield(metadata, "icon")
                app.Icon.ImageSource = fullfile(matlabroot, dirPath, Constants.DefaultTaskIcon);
            elseif isfile(fullfile(viewModel.getDirectory(), 'resources', metadata.icon))
                app.Icon.ImageSource = viewModel.resizeIconImage(fullfile(viewModel.getDirectory(), 'resources', metadata.icon));
            else
                app.Icon.ImageSource = fullfile(matlabroot, dirPath, Constants.DefaultTaskIcon);
            end

            app.BrowseIconButton.Icon = fullfile(matlabroot, dirPath, Constants.FolderIcon);

            app.Description.Value = metadata.description;
            app.Keywords.Value = metadata.keywords;
            app.DocLink.Value = metadata.docLink;

            % add listners to registration events emitted by the viewModel
            addlistener(viewModel, Constants.RegistrationErrorEvent, @app.showRegistrationError);
            addlistener(viewModel, Constants.RegistrationSuccessEvent, @app.closeMetadataUI);

            % check validity status of liveTasks.json and show message
            % accordingly
            if ~viewModel.getModelValidity()
                app.handleInvalidModel();
            end

            app.ViewModel = viewModel;
            app.Metadata = metadata;
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            import matlab.internal.task.metadata.Constants

            % do validations before proceeding with the registration
            isValidMetadata = app.ViewModel.validateName(app.Name.Value);

            if ~isValidMetadata
                return;
            end

            % show progress-dialog
            progressDialogHeader = string(message([Constants.MessageCatalogPrefix, 'TaskRegistrationHeader']));
            progressDialogMsg = string(message([Constants.MessageCatalogPrefix, 'RegistrationInProgressMsg']));
            app.ProgressDialog = uiprogressdlg(app.UIFigure,'Indeterminate','on',...
                'Message',progressDialogMsg,...
                'Title',progressDialogHeader);

            % collect metadata in a struct
            viewModel = app.ViewModel;
            metadata = app.Metadata;

            metadata.name = app.Name.Value;
            metadata.description = string(app.Description.Value);
            
            metadata.icon = app.ViewModel.copyIcon();
            metadata.keywords = app.Keywords.Value;
            metadata.docLink = app.DocLink.Value;
            metadata.taskClassName = metadata.taskClassName;

            % save metadata
            viewModel.registerTask(metadata);
        end

        % Button pushed function: BrowseIconButton
        function BrowseIconButtonPushed(app, event)
            import matlab.internal.task.metadata.Constants
            [fileName, filePath] = uigetfile( ...
                {'*.gif;*.jpeg;*.jpg;*.png;','All Image Files (*.gif,*.jpeg,*.jpg,*.png)';
                '*.*',  'All Files (*.*)'}, 'Choose a File');
            figure(app.UIFigure);

            if fileName ~= 0
                [~, ~, imageFormat] = fileparts(fileName);
                if contains(['.gif','.jpeg','.jpg','.png'], lower(imageFormat))
                    iconPath = fullfile(filePath, fileName);
                    app.Icon.ImageSource = app.ViewModel.resizeIconImage(iconPath);
                else
                    selectionDialogHeader = string(message([Constants.MessageCatalogPrefix, 'TaskRegistrationHeader']));
                    selectionDialogMsg = string(message([Constants.MessageCatalogPrefix, 'IconFileErrorMsg']));
                    uialert(app.UIFigure,selectionDialogMsg, selectionDialogHeader,...
                        'Icon','error');
                end
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.ViewModel.cleanUpApp();
            delete(app);
        end

        % Value changed function: Name
        function NameValueChanged(app, event)
            import matlab.internal.task.metadata.Constants

            % if valid assign value and return
            if app.ViewModel.validateName(event.Value)
                app.Metadata.name = event.Value;
                return;
            end

            % if invalid show error-message and restore previous value
            selectionDialogHeader = string(message([Constants.MessageCatalogPrefix, 'TaskRegistrationHeader']));
            selectionDialogMsg = string(message([Constants.MessageCatalogPrefix, 'TaskNameEmptyErrorMsg']));
            uialert(app.UIFigure,selectionDialogMsg,selectionDialogHeader,...
                'Icon','error','CloseFcn',@app.resetName);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            helpview(fullfile(docroot,'matlab', 'helptargets.map'), 'livetasks_configure_task');
        end

        % Value changed function: Keywords
        function KeywordsValueChanged(app, event)
            keywords = app.Keywords.Value;
            app.Metadata.keywords = app.ViewModel.validateAndUpdateKeywords(keywords);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'App Designer Custom UI Component Metadata';
            app.UIFigure.Tag = 'MetadataUITitle:Name';
            app.AppGrid = uigridlayout(app.UIFigure);
            app.ActionButtonGrid = uigridlayout(app.AppGrid);
            app.CancelButton = uibutton(app.ActionButtonGrid, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tag = 'CancelLabel:Text';
            app.CancelButton.Text = 'Cancel';
            app.OkButton = uibutton(app.ActionButtonGrid, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Tag = 'OkLabel:Text';
            app.OkButton.Text = 'Ok';
            app.HelpButton = uibutton(app.ActionButtonGrid, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Tag = 'HelpLabel:Text';
            app.HelpButton.Text = 'Help';
            app.MetadataGrid = uigridlayout(app.AppGrid);
            app.TaskDetailsLabel = uilabel(app.MetadataGrid);
            app.TaskDetailsLabel.Tag = 'TaskDetailsLabel:Text';
            app.TaskDetailsLabel.Text = 'Task Details';
            app.NameLabel = uilabel(app.MetadataGrid);
            app.NameLabel.Tag = 'NameLabel:Text';
            app.NameLabel.Tooltip = {'NameTooltip'};
            app.NameLabel.Text = 'Name *';
            app.IconLabel = uilabel(app.MetadataGrid);
            app.IconLabel.Tag = 'IconLabel:Text';
            app.IconLabel.Tooltip = {'IconTooltip'};
            app.IconLabel.Text = 'Icon';
            app.Name = uieditfield(app.MetadataGrid, 'text');
            app.Name.ValueChangedFcn = createCallbackFcn(app, @NameValueChanged, true);
            app.Name.Tooltip = {'NameTooltip'};
            app.Icon = uiimage(app.MetadataGrid);
            app.Icon.Tooltip = {'IconTooltip'};
            app.BrowseIconButton = uibutton(app.MetadataGrid, 'push');
            app.BrowseIconButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseIconButtonPushed, true);
            app.BrowseIconButton.Tag = 'BrowseLabel:Text';
            app.BrowseIconButton.Text = 'Browse';
            app.DescriptionLabel = uilabel(app.MetadataGrid);
            app.DescriptionLabel.Tag = 'DescriptionLabel:Text';
            app.DescriptionLabel.Tooltip = {'DescriptionTooltip'};
            app.DescriptionLabel.Text = 'Description';
            app.KeywordsLabel = uilabel(app.MetadataGrid);
            app.KeywordsLabel.Tag = 'KeywordsLabel:Text';
            app.KeywordsLabel.Tooltip = {'KeywordsTooltip'};
            app.KeywordsLabel.Text = 'Keywords';
            app.Keywords = uieditfield(app.MetadataGrid, 'text');
            app.Keywords.ValueChangedFcn = createCallbackFcn(app, @KeywordsValueChanged, true);
            app.Keywords.Tooltip = {'KeywordsTooltip'};
            app.TaskClassDefinitionFileLabel = uilabel(app.MetadataGrid);
            app.TaskClassDefinitionFileLabel.Tag = 'TaskFileLabel:Text';
            app.TaskClassDefinitionFileLabel.Text = 'Task Class Definition File';
            app.FilePath = uilabel(app.MetadataGrid);
            app.FilePath.Text = '';
            app.Description = uieditfield(app.MetadataGrid, 'text');
            app.Description.Tooltip = {'DescriptionTooltip'};
            app.DocLink = uieditfield(app.MetadataGrid, 'text');
            app.DocLink.Tooltip = {'DocLinkTooltip'};
            app.DocLinkLabel = uilabel(app.MetadataGrid);
            app.DocLinkLabel.Tag = 'DocLinkLabel:Text';
            app.DocLinkLabel.Tooltip = {'DocLinkTooltip'};
            app.DocLinkLabel.Text = 'Documentation Link';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetadataUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end