classdef addRuleDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        dialog            matlab.ui.Figure
        colorSwatchPanel  matlab.ui.container.Panel
        numericValue      matlab.ui.control.NumericEditField
        cancelButton      matlab.ui.control.Button
        okButton          matlab.ui.control.Button
        value             matlab.ui.control.DropDown
        valueType         matlab.ui.control.DropDown
        action            matlab.ui.control.DropDown
    end

    properties (Access = public)
        importToolCallback;  % the callback that is passed in to actually add the rule
        importToolCallbackData = [];
    end
    
    properties (Access = private)
        strings
        % strings include:
        % title
        % ok
        % cancel
        % replace
        % excludeRows
        % excludeCols
        % replaceUnimportable
        % replaceEmpty
        % excludeUnimportable
        % excludeEmpty
        % replaceWithNaN
        % replaceWithValue
        
        fig
        control
        allowReplaceEmpty = true;
    end
    
    methods (Access = public)
        
        % Update all values in the dialog.
        function updateAllValues(app, actionString, valueString, value, allowReplaceEmpty)
            app.allowReplaceEmpty = allowReplaceEmpty;

            % Set the action, and call its callback
            app.action.Value = actionString;
            app.actionValueChanged();
            
            % Set the value type (the callback here is a no-op)
            app.valueType.Value = valueString;
            
            % Set the value, and call its callback
            if isempty(value) || (isnumeric(value) && isnan(value)) || strcmp(value, "NaN")
                app.value.Value = message('MATLAB:codetools:importtool:DropdownOptionNaN').string;
            else
                app.value.Value = message('MATLAB:codetools:importtool:DropdownOptionValue').string;
                app.numericValue.Value = value;
            end
            app.valueValueChanged();
        end
    end
    
    methods (Access = public)
        % These methods are for unit testing
        function test_actionValueChanged(app)
            app.actionValueChanged;
        end
        
        function test_valueValueChanged(app)
            app.valueValueChanged;
        end
        
        function test_setString(app, stringName, newString)
            app.strings.(stringName) = newString;
        end
        
        function test_resize(app)
            app.resizeDialog;
        end
    end
    
    methods (Access = private)
        
        % assumes app.control is a text element in an open figure window
        % with the font set as in the dialog's dropdown boxes
        function width = stringPixelWidth(app, string)
            app.control.String = string;
            width = app.control.Extent(3);
        end
        
        function width = largestPixelWidth(app, dropdownMessages, dropdown)
            fontInfo = struct('font', dropdown.FontName, ...
                'size', dropdown.FontSize, ...
                'weight', dropdown.FontWeight, ...
                'angle', dropdown.FontAngle);
            
            app.control = uicontrol('Parent', app.fig, ...
                'Style', 'text',...
                'String', string,...
                'Visible', 'off',...
                'FontName', fontInfo.font,...
                'FontSize', fontInfo.size,...
                'FontWeight', fontInfo.weight,...
                'FontAngle', fontInfo.angle);
            widths = arrayfun(@(z) stringPixelWidth(app, z), dropdownMessages);
            width = max(widths);
        end
        
        function widths = uiElementWidths(app, dropdownMessages)
            app.fig = figure('Visible', 'off');

            widths = struct();
            widths.largestDropdown = app.largestPixelWidth(...
                dropdownMessages,...
                app.action);
            widths.ok = app.largestPixelWidth(...
                string(app.strings.ok),...
                app.okButton);
            widths.cancel = app.largestPixelWidth(...
                string(app.strings.cancel),...
                app.cancelButton);
            widths.valueDropdown = app.largestPixelWidth(...
                [string(app.strings.replaceWithNaN), string(app.strings.replaceWithValue)],...
                app.value);
            
            close(app.fig);
        end
        
        function resizeDialog(app)
            dropdownMessages = [ string(app.strings.replace)...
                string(app.strings.excludeRows)...
                string(app.strings.excludeCols)...
                string(app.strings.replaceUnimportable)...
                string(app.strings.replaceEmpty)...
                string(app.strings.excludeUnimportable)...
                string(app.strings.excludeEmpty)...
                ];
            
            width = app.uiElementWidths(dropdownMessages);
            
            borderPadding = 20;
            arrowPadding = 40;
            % spacing between two half-sized components (value, ok/cancel)
            halfSpacing = 15;
            
            % readjust sizes if any of the half component strings are too
            % long.
            largestHalfElement = max([width.ok, width.cancel, width.valueDropdown]);
            if largestHalfElement > width.largestDropdown / 2
                shortfall = largestHalfElement - width.largestDropdown / 2;
                % need to multiply shortfall by two so that both halves
                % will be increased in size to preserve same-sized halves
                width.largestDropdown = width.largestDropdown + arrowPadding + 2 * shortfall;
            end
            
            swatchWidth = 32;
            totalDropdownWidth = width.largestDropdown + arrowPadding + swatchWidth;
            
            % width of each half-sized component
            halfWidth = (totalDropdownWidth - halfSpacing) / 2;
            
            % line up all dropdowns along the left side of the window
            app.action.Position(1) = borderPadding;
            app.valueType.Position(1) = borderPadding;
            app.value.Position(1) = borderPadding;
            
            % set widths for all dropdowns
            app.action.Position(3) = totalDropdownWidth - swatchWidth;
            app.valueType.Position(3) = totalDropdownWidth;
            app.value.Position(3) = totalDropdownWidth / 2;
            
            % set position and size for value inputs
            app.value.Position(1) = borderPadding;
            app.value.Position(3) = halfWidth;
            app.numericValue.Position(1) = borderPadding + halfWidth + halfSpacing;
            app.numericValue.Position(3) = halfWidth;
            
            % set position and size for buttons
            app.okButton.Position(1) = borderPadding;
            app.okButton.Position(3) = halfWidth;
            app.cancelButton.Position(1) = borderPadding + halfWidth + halfSpacing;
            app.cancelButton.Position(3) = halfWidth;
            
            % set width of entire dialog
            app.dialog.Position(3) = borderPadding + totalDropdownWidth + borderPadding;
            
            app.colorSwatchPanel.Position(1) = totalDropdownWidth - 4;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, addRuleCallback, offsetX, offsetY, title)
            app.importToolCallback = addRuleCallback;
            app.dialog.Position(1) = offsetX;
            % adding in position 4 (height) because we need to account for
            % the height of the dialog in the new placement
            app.dialog.Position(2) = offsetY - (app.dialog.Position(4));
            
            app.strings.ok = char( message('MATLAB:codetools:importtool:NewRuleDialogOk').string );
            app.strings.cancel = char( message('MATLAB:codetools:importtool:NewRuleDialogCancel').string );
            
            app.strings.replace = char( message('MATLAB:codetools:importtool:DropdownOptionReplace').string );
            app.strings.excludeRows = char( message('MATLAB:codetools:importtool:DropdownOptionExcludeRows').string );
            app.strings.excludeCols = char( message('MATLAB:codetools:importtool:DropdownOptionExcludeCols').string );
            app.strings.replaceUnimportable = char( message('MATLAB:codetools:importtool:DropdownOptionReplaceUnimportableCells').string );
            app.strings.replaceEmpty = char( message('MATLAB:codetools:importtool:DropdownOptionReplaceEmptyCells').string );
            app.strings.excludeUnimportable = char( message('MATLAB:codetools:importtool:DropdownOptionExcludeUnimportableCells').string );
            app.strings.excludeEmpty = char( message('MATLAB:codetools:importtool:DropdownOptionExcludeEmptyCells').string );
            app.strings.replaceWithNaN = char( message('MATLAB:codetools:importtool:DropdownOptionNaN').string );
            app.strings.replaceWithValue = char( message('MATLAB:codetools:importtool:DropdownOptionValue').string );
            
            app.dialog.Name = title;
            app.okButton.Text = app.strings.ok;
            app.cancelButton.Text = app.strings.cancel;
            
            app.action.Items = {app.strings.replace, app.strings.excludeRows, app.strings.excludeCols};
            app.action.Value = {app.strings.replace};
            
            app.valueType.Items = {app.strings.replaceUnimportable, app.strings.replaceEmpty};
            app.valueType.Value = {app.strings.replaceUnimportable};
            
            app.value.Items = {app.strings.replaceWithNaN, app.strings.replaceWithValue};
            app.value.Value = {app.strings.replaceWithNaN};
            
            app.colorSwatchPanel.BackgroundColor = [1.0000, 0.9373, 0.6980];
            
            app.resizeDialog;
            app.dialog.WindowStyle = "modal";
        end

        % Value changed function: value
        function valueValueChanged(app, event)
            val = app.value.Value;
            if strcmp(val, app.strings.replaceWithValue) == true
                app.numericValue.Visible = true;
            else
                app.numericValue.Visible = false;
            end
        end

        % Value changed function: action
        function actionValueChanged(app, event)
            val = app.action.Value;
            
            if strcmp(val, app.strings.replace) == true
                app.value.Visible = true;
                app.colorSwatchPanel.BackgroundColor = [1.0000, 0.9373, 0.6980];
                if app.allowReplaceEmpty
                    app.valueType.Items = {app.strings.replaceUnimportable, app.strings.replaceEmpty};
                else
                    app.valueType.Items = {app.strings.replaceUnimportable};
                end
                if strcmp(app.value.Value, app.strings.replaceWithValue) == true
                    app.numericValue.Visible = true;
                else
                    app.numericValue.Visible = false;
                end
            else
                app.colorSwatchPanel.BackgroundColor = [0.8039, 0.7098, 0.8431];
                app.value.Visible = false;
                app.numericValue.Visible = false;
                if app.allowReplaceEmpty
                    app.valueType.Items = {app.strings.excludeUnimportable, app.strings.excludeEmpty};
                else
                    app.valueType.Items = {app.strings.excludeUnimportable};
                end
            end
        end

        % Value changed function: valueType
        function valueTypeValueChanged(app, event)
            
        end

        % Button pushed function: okButton
        function okButtonPushed(app, event)
            if strcmp(app.action.Value, app.strings.replace)
                if strcmp(app.value.Value, app.strings.replaceWithNaN)
                    val = app.strings.replaceWithNaN;
                else
                    val = app.numericValue.Value;
                end
            else
                % replacement value is irrelevant if this isn't a 'replace'
                % option
                val = '';
            end
            
            app.importToolCallback(internal.matlab.importtool.server.rules.RulesUtils.actionToEnum(app.action.Value), ...
                internal.matlab.importtool.server.rules.RulesUtils.valueTypeToEnum(app.valueType.Value), ...
                val, app.importToolCallbackData);
            app.delete();
        end

        % Button pushed function: cancelButton
        function cancelButtonPushed(app, event)
            app.delete();
        end

        % Callback function
        function numericValueValueChanging(app, event)
            
        end

        % Callback function
        function numericValueValueChanged(app, event)
            val = event.Value;
            prev = event.PreviousValue;
            % check if string is numeric
            if isempty(str2double(val))
                % revert to original value if non-numeric character was
                % added
                app.numericValue.Value = prev;
            end
        end

        % Value changed function: numericValue
        function numericValueValueChanged2(app, event)
            app.okButtonPushed();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.dialog = uifigure('Visible', 'off');
            app.action = uidropdown(app.dialog);
            app.action.Items = {};
            app.action.ValueChangedFcn = createCallbackFcn(app, @actionValueChanged, true);
            app.action.Value = {};
            app.valueType = uidropdown(app.dialog);
            app.valueType.Items = {};
            app.valueType.ValueChangedFcn = createCallbackFcn(app, @valueTypeValueChanged, true);
            app.valueType.Value = {};
            app.value = uidropdown(app.dialog);
            app.value.Items = {};
            app.value.ValueChangedFcn = createCallbackFcn(app, @valueValueChanged, true);
            app.value.Value = {};
            app.okButton = uibutton(app.dialog, 'push');
            app.okButton.ButtonPushedFcn = createCallbackFcn(app, @okButtonPushed, true);
            app.okButton.Text = '';
            app.cancelButton = uibutton(app.dialog, 'push');
            app.cancelButton.ButtonPushedFcn = createCallbackFcn(app, @cancelButtonPushed, true);
            app.cancelButton.Text = '';
            app.numericValue = uieditfield(app.dialog, 'numeric');
            app.numericValue.ValueChangedFcn = createCallbackFcn(app, @numericValueValueChanged2, true);
            app.numericValue.Visible = 'off';
            app.colorSwatchPanel = uipanel(app.dialog);
            % Show the figure after all components are created
            app.dialog.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = addRuleDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.dialog)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.dialog)
        end
    end
end