classdef rowTimesDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfigureRowTimesUIFigure  matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        ButtonPanel                matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        CancelButton               matlab.ui.control.Button
        OkButton                   matlab.ui.control.Button
        BottomPanel                matlab.ui.container.Panel
        BottomPanelLayout          matlab.ui.container.GridLayout
        DurationValue              matlab.ui.control.EditField
        DatetimeTimeValue          matlab.ui.control.EditField
        DatetimeDateValue          matlab.ui.control.DatePicker
        ButtonGroup_4              matlab.ui.container.ButtonGroup
        HiddenButton4              matlab.ui.control.RadioButton
        DatetimeButton             matlab.ui.control.RadioButton
        ButtonGroup_3              matlab.ui.container.ButtonGroup
        HiddenButton3              matlab.ui.control.RadioButton
        DurationButton             matlab.ui.control.RadioButton
        StartTimeLabel             matlab.ui.control.Label
        TopPanel                   matlab.ui.container.Panel
        TopPanelLayout             matlab.ui.container.GridLayout
        SampleRateValue            matlab.ui.control.Spinner
        TimeStepValue              matlab.ui.control.Spinner
        TimeStepCB                 matlab.ui.control.DropDown
        HzLabel                    matlab.ui.control.Label
        ButtonGroup_2              matlab.ui.container.ButtonGroup
        HiddenButton2              matlab.ui.control.RadioButton
        SampleRateButton           matlab.ui.control.RadioButton
        ButtonGroup                matlab.ui.container.ButtonGroup
        HiddenButton1              matlab.ui.control.RadioButton
        TimeStepButton             matlab.ui.control.RadioButton
    end


    properties (Access = private)
        TimeStepValues = ["calyears", "calquarters", "calmonths", "calweeks", "caldays", "hours", "minutes", "seconds", "milliseconds"];
        OkButtonFcn = [];
        CancelButtonFcn = [];
    end

    properties (Access = public)
        State;
        SavedValues = [];
    end

    methods (Access = private)

        function initText(app)
            app.ConfigureRowTimesUIFigure.Name = getString(message("MATLAB:codetools:importtool:ImportTool_RowTimes_Title"));
            app.TimeStepButton.Text = getString(message("MATLAB:codetools:importtool:ImportTool_RowTimes_TimeStep"));
            app.SampleRateButton.Text = getString(message("MATLAB:codetools:importtool:ImportTool_RowTimes_SampleRate"));
            app.HzLabel.Text = getString(message("MATLAB:codetools:importtool:ImportTool_RowTimes_HertzAbbreviation"));
            app.DurationButton.Text = getString(message("MATLAB:codetools:importtool:ImportTool_RowTimes_DurationRadioBtn"));
            app.DatetimeButton.Text = getString(message("MATLAB:codetools:importtool:ImportTool_RowTimes_DatetimeRadioBtn"));
            app.OkButton.Text = getString(message("MATLAB:codetools:importtool:ImportTool_RowTimes_OkBtn"));
            app.CancelButton.Text = getString(message("MATLAB:codetools:importtool:ImportTool_RowTimes_CancelBtn"));
            try
                app.StartTimeLabel.Text = getString(message("MATLAB:codetools:importtool:ImportTool_RowTimes_StartTime"));
            catch
            end

            app.DatetimeTimeValue.Tooltip = getString(message("MATLAB:codetools:importtool:ImportTool_RowTimes_TimeFormatTooltip"));
            app.DurationValue.Tooltip = getString(message("MATLAB:codetools:importtool:ImportTool_RowTimes_TimeFormatTooltip"));

            app.TimeStepCB.Items = arrayfun(@(x) getString(message("MATLAB:codetools:importtool:ImportTool_RowTimes_TimeStep_" + x)), ...
                app.TimeStepValues, 'UniformOutput', false);
            app.TimeStepCB.Value = getString(message("MATLAB:codetools:importtool:ImportTool_RowTimes_TimeStep_seconds"));
        end

        function initDefaults(app)
            app.SampleRateButton.Value = false;
            app.SampleRateValue.Enable = false;
            app.HzLabel.Enable = false;

            app.DatetimeButton.Value = false;
            app.DatetimeDateValue.Enable = false;
            app.DatetimeTimeValue.Enable = false;
        end

        function showError(app, errorMsg)
            app.State = getString(message(errorMsg));
            uialert(app.ConfigureRowTimesUIFigure, app.State, ...
                getString(message("MATLAB:codetools:importtool:ImportTool_RowTimes_ErrorTitle")));
        end

        function revertToSavedValues(app)
            if isempty(app.SavedValues)
                return;
            end

            app.TimeStepButton.Value = app.SavedValues.TimeStep;
            app.SampleRateButton.Value = app.SavedValues.SampleRate;

            if app.SavedValues.TimeStep
                app.TimeStepValue.Value = app.SavedValues.TimeStepValue;
                app.TimeStepCB.Value = app.TimeStepCB.Items(strcmp(app.SavedValues.TimeStepUnits, app.TimeStepValues));
                app.SampleRateValue.Value = 1;
                app.TimeStepGroupChanged();
            else
                app.SampleRateValue.Value = app.SavedValues.SampleRateValue;
                app.TimeStepCB.Value = app.TimeStepCB.Items(strcmp("seconds", app.TimeStepValues));
                app.TimeStepValue.Value = 1;
                app.SampleRateGroupChanged();
            end

            app.DurationButton.Value = app.SavedValues.StartTimeDuration;
            app.DatetimeButton.Value = app.SavedValues.StartTimeDatetime;
            if app.DurationButton.Value
                app.DurationValue.Value = string(app.SavedValues.DurationValue);
                app.DatetimeDateValue.Value = NaT;
                app.DatetimeTimeValue.Value = '00:00:00';
                app.DurationGroupChanged([]);
            else
                app.DatetimeDateValue.Value = app.SavedValues.DatetimeValue;
                dt = app.SavedValues.DatetimeValue;
                dt.Format = "HH:mm:ss";
                app.DatetimeTimeValue.Value = string(dt);
                app.DurationValue.Value = '00:00:00';
                app.DatetimeGroupChanged();
            end
        end

        function [values, status] = getValues(app)
            values = struct;
            status = "";
            values.TimeStep = app.TimeStepButton.Value;
            values.SampleRate = ~app.TimeStepButton.Value;

            if app.TimeStepButton.Value
                values.TimeStepValue = app.TimeStepValue.Value;
                if values.TimeStepValue <= 0 || values.TimeStepValue > 1e6
                    status = "MATLAB:codetools:importtool:ImportTool_RowTimes_TimeStepInvalid";
                end
                values.TimeStepUnits = app.TimeStepValues(strcmp(app.TimeStepCB.Value, app.TimeStepCB.Items));
            else
                values.SampleRateValue = app.SampleRateValue.Value;
                if values.SampleRateValue <= 0 || values.SampleRateValue > 1e6
                    status = "MATLAB:codetools:importtool:ImportTool_RowTimes_SampleRateInvalid";
                end
            end

            values.StartTimeDuration = app.DurationButton.Value;
            values.StartTimeDatetime = ~app.DurationButton.Value;
            if app.DurationButton.Value
                try
                    dr = [];
                    drText = app.DurationValue.Value;
                    if count(drText, ":") == 1
                        dr = duration(drText, "InputFormat", "mm:ss");
                    else
                        dr = duration(drText);
                    end

                    % Don't allow negative durations
                    if dr < duration(0,0,0)
                        status = "MATLAB:codetools:importtool:ImportTool_RowTimes_InvalidDurationFormat";
                    end
                catch
                    status = "MATLAB:codetools:importtool:ImportTool_RowTimes_InvalidDurationFormat";
                end
                values.DurationValue = dr;
            else
                dt = app.DatetimeDateValue.Value;
                try
                    dtText = app.DatetimeTimeValue.Value;
                    if ~isempty(dtText)
                        dtText = split(dtText, ":");
                        if any(startsWith(dtText, "-"))
                            dt = NaT;
                        elseif length(dtText) == 2
                            dt.Hour = str2double(dtText(1));
                            dt.Minute = str2double(dtText(2));
                            dt.Format = "default";
                        elseif length(dtText) == 3
                            dt.Hour = str2double(dtText(1));
                            dt.Minute = str2double(dtText(2));
                            dt.Second = str2double(dtText(3));
                            dt.Format = "default";
                        else
                            dt = NaT;
                        end
                    end
                catch
                    dt = NaT;
                end

                if isnat(dt)
                    status = "MATLAB:codetools:importtool:ImportTool_RowTimes_InvalidDatetimeFormat";
                end

                values.DatetimeValue = dt;
            end
        end
    end

    methods (Access = public)
        function b = isStartTimeConflict(~, values)
            b = false;
            if values.TimeStep && ...
                    any(values.TimeStepUnits == ["calyears", "calquarters", "calmonths", "calweeks", "caldays"]) && ...
                    values.StartTimeDuration
                b = true;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            import matlab.internal.capability.Capability;

            persistent p
            if isempty(p)
                p = inputParser;
                addParameter(p, "OkButtonFcn", [], ...
                    @(x) validateattributes(x, "function_handle", "nonempty"));
                addParameter(p, "CancelButtonFcn", [], ...
                    @(x) validateattributes(x, "function_handle", "nonempty"));
                addParameter(p, "Position", [], ...
                    @(x) validateattributes(x, "double", "2d"));
            end
            parse(p, varargin{:})
            args = p.Results;

            app.OkButtonFcn = args.OkButtonFcn;
            app.CancelButtonFcn = args.CancelButtonFcn;

            app.initText();
            app.initDefaults();

            if ~isempty(args.Position)
                app.ConfigureRowTimesUIFigure.Position(1) = args.Position(1);
                % adding in position 4 (height) because we need to account for
                % the height of the dialog in the new placement
                app.ConfigureRowTimesUIFigure.Position(2) = args.Position(2) - (app.ConfigureRowTimesUIFigure.Position(4));
            end

            try
                if ~internal.matlab.importtool.peer.ImportToolManager.setDebug && ...
                        (matlab.internal.feature('webui') && Capability.isSupported(Capability.LocalClient))
                    app.ConfigureRowTimesUIFigure.WindowStyle = "modal";
                end
            catch
            end

            app.SavedValues = getValues(app);
        end

        % Selection changed function: ButtonGroup
        function TimeStepGroupChanged(app, event)
            app.SampleRateButton.Value = false;

            app.TimeStepCB.Enable = true;
            app.TimeStepValue.Enable = true;

            app.SampleRateValue.Enable = false;
            app.HzLabel.Enable = false;
        end

        % Selection changed function: ButtonGroup_2
        function SampleRateGroupChanged(app, event)
            app.TimeStepButton.Value = false;

            app.SampleRateValue.Enable = true;
            app.HzLabel.Enable = true;

            app.TimeStepCB.Enable = false;
            app.TimeStepValue.Enable = false;
        end

        % Selection changed function: ButtonGroup_3
        function DurationGroupChanged(app, event)
            app.DatetimeButton.Value = false;

            app.DurationValue.Enable = true;

            app.DatetimeDateValue.Enable = false;
            app.DatetimeTimeValue.Enable = false;
        end

        % Selection changed function: ButtonGroup_4
        function DatetimeGroupChanged(app, event)
            app.DurationButton.Value = false;

            app.DatetimeDateValue.Enable = true;
            app.DatetimeTimeValue.Enable = true;

            app.DurationValue.Enable = false;
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            app.State = [];
            [values, status] = getValues(app);

            if strlength(status) > 0
                app.showError(status);
                return;
            end
            if app.isStartTimeConflict(values)
                app.showError("MATLAB:codetools:importtool:ImportTool_RowTimes_ConflictStartTime");
                return
            end
            app.ConfigureRowTimesUIFigure.WindowStyle = "normal";
            app.ConfigureRowTimesUIFigure.Visible = false;
            app.SavedValues = values;

            if ~isempty(app.OkButtonFcn)
                app.OkButtonFcn(values);
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.ConfigureRowTimesUIFigure.WindowStyle = "normal";
            app.ConfigureRowTimesUIFigure.Visible = false;
            app.revertToSavedValues();

            if ~isempty(app.CancelButtonFcn)
                app.CancelButtonFcn();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConfigureRowTimesUIFigure = uifigure('Visible', 'off');
            app.ConfigureRowTimesUIFigure.Name = 'Configure Row Times';
            app.GridLayout = uigridlayout(app.ConfigureRowTimesUIFigure);
            app.TopPanel = uipanel(app.GridLayout);
            app.TopPanelLayout = uigridlayout(app.TopPanel);
            app.ButtonGroup = uibuttongroup(app.TopPanelLayout);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TimeStepGroupChanged, true);
            app.TimeStepButton = uiradiobutton(app.ButtonGroup);
            app.TimeStepButton.Text = 'Time Step';
            app.TimeStepButton.Value = true;
            app.HiddenButton1 = uiradiobutton(app.ButtonGroup);
            app.HiddenButton1.Text = 'Button';
            app.ButtonGroup_2 = uibuttongroup(app.TopPanelLayout);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @SampleRateGroupChanged, true);
            app.SampleRateButton = uiradiobutton(app.ButtonGroup_2);
            app.SampleRateButton.Text = 'Sample Rate';
            app.SampleRateButton.Value = true;
            app.HiddenButton2 = uiradiobutton(app.ButtonGroup_2);
            app.HiddenButton2.Text = 'Button';
            app.HzLabel = uilabel(app.TopPanelLayout);
            app.HzLabel.Text = 'Hz';
            app.TimeStepCB = uidropdown(app.TopPanelLayout);
            app.TimeStepCB.Items = {'days', 'minutes', 'hours', 'seconds'};
            app.TimeStepCB.Value = 'seconds';
            app.TimeStepValue = uispinner(app.TopPanelLayout);
            app.TimeStepValue.Limits = [1 1000000];
            app.TimeStepValue.Value = 1;
            app.SampleRateValue = uispinner(app.TopPanelLayout);
            app.SampleRateValue.Limits = [0 1000000];
            app.SampleRateValue.Value = 1;
            app.StartTimeLabel = uilabel(app.GridLayout);
            app.StartTimeLabel.Text = 'Start Time';
            app.BottomPanel = uipanel(app.GridLayout);
            app.BottomPanelLayout = uigridlayout(app.BottomPanel);
            app.ButtonGroup_3 = uibuttongroup(app.BottomPanelLayout);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @DurationGroupChanged, true);
            app.DurationButton = uiradiobutton(app.ButtonGroup_3);
            app.DurationButton.Text = 'Duration';
            app.DurationButton.Value = true;
            app.HiddenButton3 = uiradiobutton(app.ButtonGroup_3);
            app.HiddenButton3.Text = 'Button';
            app.ButtonGroup_4 = uibuttongroup(app.BottomPanelLayout);
            app.ButtonGroup_4.SelectionChangedFcn = createCallbackFcn(app, @DatetimeGroupChanged, true);
            app.DatetimeButton = uiradiobutton(app.ButtonGroup_4);
            app.DatetimeButton.Text = 'Datetime';
            app.DatetimeButton.Value = true;
            app.HiddenButton4 = uiradiobutton(app.ButtonGroup_4);
            app.HiddenButton4.Text = 'Button';
            app.DatetimeDateValue = uidatepicker(app.BottomPanelLayout);
            app.DatetimeTimeValue = uieditfield(app.BottomPanelLayout, 'text');
            app.DatetimeTimeValue.Tooltip = {'hh:mm:ss'};
            app.DatetimeTimeValue.Value = '00:00:00';
            app.DurationValue = uieditfield(app.BottomPanelLayout, 'text');
            app.DurationValue.Tooltip = {'hh:mm:ss'};
            app.DurationValue.Value = '00:00:00';
            app.ButtonPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.ButtonPanel);
            app.OkButton = uibutton(app.GridLayout2, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.ConfigureRowTimesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rowTimesDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConfigureRowTimesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfigureRowTimesUIFigure)
        end
    end
end