classdef ImportDataUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImportDataUIFigure  matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        PreviewPanel        matlab.ui.container.Panel
        PreviewGrid         matlab.ui.container.GridLayout
        SelectAllCheckBox   matlab.ui.control.CheckBox
        DontShowCheckBox    matlab.ui.control.CheckBox
        PreviewLabel        matlab.ui.control.Label
        VariablesTable      matlab.ui.control.Table
        VariablesLabel      matlab.ui.control.Label
        ButtonPanel         matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        CancelButton        matlab.ui.control.Button
        ImportButton        matlab.ui.control.Button
        questionLabel       matlab.ui.control.Label
    end


    properties (Access = private)
        ImportFcn
        CancelFcn
        FileName
        FileType
        VariableNames
        Variables
        ImportProvider
        ErrorState
    end

    methods (Access = private)

        function initializeText(app)
            app.ImportDataUIFigure.Name = getString(message(...
                "MATLAB:datatools:importdata:ImportDataTitle"));

            app.CancelButton.Text = getString(message(...
                "MATLAB:datatools:importdata:CancelButton"));
            app.ImportButton.Text = getString(message(...
                "MATLAB:datatools:importdata:ImportButton"));
            app.PreviewLabel.Text = getString(message(...
                "MATLAB:datatools:importdata:PreviewLabel"));
            app.VariablesLabel.Text = getString(message(...
                "MATLAB:datatools:importdata:VariablesLabel"));
            app.SelectAllCheckBox.Text = getString(message(...
                "MATLAB:datatools:importdata:SelectAllLabel"));
            app.ImportDataUIFigure.Name = getString(message(...
                "MATLAB:datatools:importdata:ImportDataTitle"));

            if isa(app.ImportProvider, "matlab.internal.importdata.WorkspaceProvider")
                app.questionLabel.Text = getString(message(...
                    "MATLAB:datatools:importdata:ImportVariables"));
            else
                [~, fname, ext] = fileparts(app.FileName);
                app.questionLabel.Text = getString(message(...
                    "MATLAB:datatools:importdata:ImportDataQuestion", fname + ext));
            end
            app.DontShowCheckBox.Text = getString(message(...
                "MATLAB:datatools:importdata:DontShowLabel", app.FileType));
        end

        function setupVariablesTable(app, provider)
            [app.VariableNames, app.Variables] = provider.getVariables();

            w = matlab.internal.datatoolsservices.getWorkspaceDisplay(app.Variables);

            if ~isempty(w)
                t = struct2table(w);
                t = removevars(t, "IsSummary");
                t.(1) = app.VariableNames;
            else
                t = table([], [], [], []);
            end

            t.Properties.VariableNames = {...
                getString(message("MATLAB:codetools:variableeditor:Name")), ...
                getString(message("MATLAB:codetools:variableeditor:Size")), ...
                getString(message("MATLAB:codetools:variableeditor:Class")), ...
                getString(message("MATLAB:codetools:variableeditor:Value"))};

            if isa(provider, "matlab.internal.importdata.ImportVarSelectionProvider")
                t = addvars(t, false(height(t), 1), 'Before', 1);
                t.Properties.VariableNames{1} = getString(message("MATLAB:datatools:importdata:ImportCheckbox"));
                app.VariablesTable.ColumnEditable = [true, false(1,4)];
            end

            app.VariablesTable.Data = t;
            app.VariablesTable.ColumnName = t.Properties.VariableNames;
            app.VariablesTable.Visible = 'on';
        end

        function showPreview(app, provider)
            pos = [10, 10, 470, 185];
            provider.showPreview(app.PreviewGrid, app.Variables, pos);
        end
    end
    
    methods (Access = public)
        
        function res = getErrorState(app)
            res = app.ErrorState;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, provider, varargin)
            p = inputParser;
            addParameter(p, "ImportFcn", [], @(x) validateattributes(x, ...
                {'function_handle'}, {'scalar'}));
            addParameter(p, "CancelFcn", [], @(x) validateattributes(x, ...
                {'function_handle'}, {'scalar'}));
            parse(p, varargin{:});
            args = p.Results;

            app.FileName = string(provider.getFullFilename);
            app.FileType = string(provider.FileType);
            app.ImportFcn = args.ImportFcn;
            app.CancelFcn = args.CancelFcn;
            app.ImportProvider = provider;

            try
                app.initializeText();
                app.setupVariablesTable(provider);

                if isa(provider, "matlab.internal.importdata.ImportPreviewProvider")
                    matlab.graphics.internal.themes.specifyThemePropertyMappings(app.PreviewPanel, ...
                        "BackgroundColor", "--mw-graphics-backgroundColor-axes-primary");
                    matlab.graphics.internal.themes.specifyThemePropertyMappings(app.PreviewGrid, ...
                        "BackgroundColor", "--mw-graphics-backgroundColor-axes-primary");
                    app.showPreview(provider);
                    app.GridLayout.RowHeight{3} = 0;
                elseif isa(provider, "matlab.internal.importdata.ImportOptionsProvider")
                    app.PreviewPanel.BorderType = "none";
                    matlab.graphics.internal.themes.specifyThemePropertyMappings(app.PreviewPanel, ...
                        "BackgroundColor", "--mw-backgroundColor-primary");
                    matlab.graphics.internal.themes.specifyThemePropertyMappings(app.PreviewGrid, ...
                        "BackgroundColor", "--mw-backgroundColor-primary");
                    app.PreviewLabel.Visible = 'off';
                    app.GridLayout.RowHeight{5} = 0;
                    app.GridLayout.RowHeight{3} = 0;

                    accordion = matlab.ui.container.internal.Accordion('Parent', app.PreviewGrid);
                    provider.fileSelected(accordion, app.PreviewGrid);
                else
                    app.PreviewPanel.Visible = 'off';
                    app.PreviewLabel.Visible = 'off';

                    if isa(provider, "matlab.internal.importdata.ImportVarSelectionProvider")
                        % make the table taller, and the dialog shorter/wider
                        app.GridLayout.RowHeight{4} = '1x';
                        app.GridLayout.RowHeight{5} = 0;
                        app.GridLayout.RowHeight{6} = 0;

                        app.SelectAllCheckBox.Value = false;
                        app.SelectAllCheckBox.Visible = 'on';
                    else
                        app.GridLayout.RowHeight{3} = 0;
                        app.GridLayout.RowHeight{5} = 0;
                    end
                end
            catch ex
                app.ErrorState = ex;
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            if ~isempty(app.ImportFcn)

                if isa(app.ImportProvider, "matlab.internal.importdata.ImportVarSelectionProvider")
                    names = app.VariablesTable.Data{:,2};
                    names = names(app.VariablesTable.Data{:,1});
                else
                    names = app.VariablesTable.Data{:,1};
                end
                app.ImportFcn(app.FileName, string(names));
            end
            app.ImportDataUIFigure.Visible = 'off';

            if isa(app.ImportProvider, "matlab.internal.importdata.ImportPreviewProvider")
                app.ImportProvider.previewHidden();
            end
            delete(app.ImportDataUIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.ImportDataUIFigure.Visible = 'off';
            
            if ~isempty(app.CancelFcn)
                app.CancelFcn(app.FileName);
            end

            if isa(app.ImportProvider, "matlab.internal.importdata.ImportPreviewProvider")
                app.ImportProvider.previewHidden();
            end
            delete(app.ImportDataUIFigure);
        end

        % Value changed function: SelectAllCheckBox
        function SelectAllCheckBoxValueChanged(app, event)
            value = app.SelectAllCheckBox.Value;
            tb = app.VariablesTable.Data;
            if value
                tb.(1) = true(height(tb), 1);
            else
                tb.(1) = false(height(tb), 1);
            end
            app.VariablesTable.Data = tb;
            app.ImportButton.Enable = any(app.VariablesTable.Data.(1));
        end

        % Cell edit callback: VariablesTable
        function VariablesTableCellEdit(app, event)
            selected = app.VariablesTable.Data.(1);
            app.SelectAllCheckBox.Value = all(selected);
            app.ImportButton.Enable = any(selected);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImportDataUIFigure = uifigure('Visible', 'off');
            app.ImportDataUIFigure.Name = 'Import Data';
            app.GridLayout = uigridlayout(app.ImportDataUIFigure);
            app.questionLabel = uilabel(app.GridLayout);
            app.questionLabel.Text = 'Import sample.XYZ';
            app.ButtonPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.ButtonPanel);
            app.ImportButton = uibutton(app.GridLayout2, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Tag = 'importButton';
            app.ImportButton.Text = 'Import';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tag = 'cancelButton';
            app.CancelButton.Text = 'Cancel';
            app.VariablesLabel = uilabel(app.GridLayout);
            app.VariablesLabel.Text = 'Variables';
            app.VariablesTable = uitable(app.GridLayout);
            app.VariablesTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.VariablesTable.RowName = {};
            app.VariablesTable.CellEditCallback = createCallbackFcn(app, @VariablesTableCellEdit, true);
            app.VariablesTable.Visible = 'off';
            app.PreviewLabel = uilabel(app.GridLayout);
            app.PreviewLabel.Text = 'Preview';
            app.DontShowCheckBox = uicheckbox(app.GridLayout);
            app.DontShowCheckBox.Text = 'Don''t Show';
            app.SelectAllCheckBox = uicheckbox(app.GridLayout);
            app.SelectAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectAllCheckBoxValueChanged, true);
            app.SelectAllCheckBox.Visible = 'off';
            app.SelectAllCheckBox.Text = 'Select All';
            app.PreviewPanel = uipanel(app.GridLayout);
            app.PreviewGrid = uigridlayout(app.PreviewPanel);
            % Show the figure after all components are created
            app.ImportDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportDataUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImportDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImportDataUIFigure)
        end
    end
end