classdef CustomFunctionDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataCleanerCustomFunctionDialogUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        WorkflowDescriptionLabel        matlab.ui.control.Label
        SyntaxLabel                     matlab.ui.control.Label
        AddAFunctionLabel               matlab.ui.control.Label
        GridLayout3                     matlab.ui.container.GridLayout
        CancelButton                    matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        OperationTypeButtonGroup        matlab.ui.container.ButtonGroup
        SingleColumnVariableOperationButton  matlab.ui.control.RadioButton
        TableWideOperationButton        matlab.ui.control.RadioButton
        DescriptionEditField            matlab.ui.control.EditField
        DescriptionEditFieldLabel       matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        AddCustomCleaningFunctionLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        FunctionName % FunctionName
    end

    methods (Access = public)
        
        function code = generateCode(app)
            if app.TableWideOperationButton.Value
                inputVarName = "inputTable";
                outputVarName = "outputTable";
            else
                inputVarName = "inputTableColumn";
                outputVarName = "outputTableColumn";
            end
            
            functionName = app.NameEditField.Value;
            functionExists = false;
            if exist(functionName, "builtin") || ~isempty(which(functionName))
                app.FunctionName = genvarname(functionName + "Wrapper");
                functionExists = true;
            else
                app.FunctionName = genvarname(functionName);
            end
            
            code = sprintf("function %s = %s(%s) %% , arg1, arg2, arg3, arg4)\n", outputVarName, app.FunctionName, inputVarName);
            code = code + sprintf("%% %s\n\n", app.DescriptionEditField.Value);
%             code = code + sprintf("\targuments\n");
%             code = code + sprintf("\t\t%% Default input argument.\n");
%             code = code + sprintf("\t\t%s\n\n", inputVarName);
%             code = code + sprintf("\t\t%% Examples: \n");
%             code = code + sprintf("\t\t%%\t arg1 (1,1) double {mustBeInRange(arg1, 0, 100, 'inclusive')} = 10; %% Scalar double from 1-100 inclusive.  Numeric Selector in UI\n");
%             code = code + sprintf("\t\t%%\t arg2 (1,1) string {mustBeMember(arg2, [""a"", ""b"", ""c""])} = ""b""; %% Scalar string must be a,b,c. Dropdown list in UI\n");
%             code = code + sprintf("\t\t%%\t arg3 (1,1) logical = true; %% Scalar logical.  Checkbox in UI (selected).\n");
%             code = code + sprintf("\t\t%%\t arg4 = ""defaultValue""; %% Any type, no size limit.  Textbox in UI.\n");
%             code = code + sprintf("\tend\n\n");
            code = code + sprintf("\t%% Assign output value\n");
            code = code + sprintf("\t%% \tExamples:\n");
            if app.TableWideOperationButton.Value
                code = code + sprintf("\t%%\t  %s.('Sum of V1 & V2') = %s.('Variable 1') + %s.('Variable 2'); %% Create a new Variable with Sum of Variable 1 and Variable 2\n", outputVarName, inputVarName, inputVarName);
            else
                code = code + sprintf("\t%%\t  %s = log(%s); %% Take log of column vector\n", outputVarName, inputVarName);
                code = code + sprintf("\t%%\t  %s = %s * 2; %% Multiply column vector by 2\n", outputVarName, inputVarName);
            end
            if functionExists
                code = code + sprintf("\t%s = %s(%s);\n", outputVarName, functionName, inputVarName);
            else
                if app.TableWideOperationButton.Value
                    comment = " % First copy input table to output table";
                else
                    comment = "";
                end
                code = code + sprintf("\t%s = %s;%s\n", outputVarName, inputVarName, comment);
            end
            code = code + sprintf("end\n");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ss = get(0, 'ScreenSize');
            app.DataCleanerCustomFunctionDialogUIFigure.Position = [(ss(3)-646)/2 (ss(4)-390)/2 646 390];
            
            % Internationalization
            app.DataCleanerCustomFunctionDialogUIFigure.Name = getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:DIALOG_TITLE'));
            app.AddCustomCleaningFunctionLabel.Text = getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:TITLE'));
            app.NameEditFieldLabel.Text = getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:FCN_NAME')) + ":";
            app.NameEditField.Tooltip = {getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:FCN_NAME_TT'))};
            app.DescriptionEditFieldLabel.Text = getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:FCN_DESCRIPTION')) + ":";
            app.DescriptionEditField.Tooltip = {getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:FCN_DESCRIPTION_TT'))};
            app.DescriptionEditField.Value = getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:FCN_DESCRIPTION_DEFAULT'));
            app.OperationTypeButtonGroup.Title = getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:OPERATION_TYPE'));
            app.TableWideOperationButton.Text = getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:TABLE_OPERATION_LABEL'));
            app.TableWideOperationButton.Tooltip = {getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:TABLE_OPERATION_TOOLTIP'))};
            app.SingleColumnVariableOperationButton.Text = getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:COLUMN_OPERATION_LABEL'));
            app.SingleColumnVariableOperationButton.Tooltip = {getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:COLUMN_OPERATION_TOOLTIP'))};
            app.OKButton.Text = getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:OK'));
            app.CancelButton.Text = getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:CANCEL'));
            app.AddAFunctionLabel.Text = getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:ADD_CUSTOM_FCN_LABEL'));
            app.SyntaxLabel.Text = ['[outputTable] = ' getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:FCN_NAME')) '(inputTable, <optArgs>...)'];
            app.WorkflowDescriptionLabel.Text = getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:WORKFLOW_DESCRIPTION_TABLE'));
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            code = app.generateCode;
            filename = app.FunctionName + ".m";
            path = fullfile(prefdir, 'Custom_Preprocessing');
            %path = fullfile(basePath, '+user', '+preprocessing');
            if ~exist(path, 'dir')
                mkdir(path);
            end
            filepath = fullfile(path, filename);
            try
                % Save code file
                f = fopen(filepath, 'w');
                fprintf(f, "%s", code);
                fclose(f);
            catch e
                uialert(app.DataCleanerCustomFunctionDialogUIFigure, e.message);
                return;
            end

            % Add to gallery
            addpath(path);
            u = uifigure('Visible', false);
            fullFunctionName = app.FunctionName; %"user.preprocessing" + app.FunctionName;
            cppf = matlab.internal.preprocessingApp.tasks.CustomPPFunctionWrapper(u, "base", "FunctionName", fullFunctionName, 'HasTableVariable', app.SingleColumnVariableOperationButton.Value);
            cppf.addTaskToFactory;

            % Save the Custom Preprocessing Task MetaData to disk so that
            % it can be automatically added to the gallery the next time
            % MATLAB is launched.
%             atf = matlab.internal.preprocessingApp.tasks.AppTaskFactory.getInstance;
%             userAuthoredTasks = atf.getUserAuthoredTasks();
%             taskData = struct('Name', num2cell([userAuthoredTasks.Name]), ...
%                 'Description', num2cell([userAuthoredTasks.Description]), ...
%                 'Group', num2cell([userAuthoredTasks.Group]), ...
%                 'Path', num2cell([userAuthoredTasks.Path]));
%           
            % Create a resources folder for the user authored task metadata
            path = fullfile(prefdir, 'Custom_Preprocessing', 'resources');
            if ~isfolder(path)
                mkdir(path); 
            end

            % Extract the relevant metadata as a struct so that it may be
            % saved to disk as JSON
            currentTaskData = struct('Filename', filename, ...
                'Name', app.NameEditField.Value, ...
                'Description', app.DescriptionEditField.Value, ...
                'HasTableVariable', app.SingleColumnVariableOperationButton.Value);            
            
            % If user custom actions are already saved, append to the
            % existing struct array, else save a fresh struct with metadata.
            path = fullfile(path, 'customPreprocessing.json');
            if isfile(path)
                userAuthoredTasks = jsondecode(fileread(path));
                userAuthoredTasks(end+1) = currentTaskData;
            else
                userAuthoredTasks = currentTaskData;   
            end
            
            % Save tasks to the disk as JSON
            encodedTaskData = jsonencode(userAuthoredTasks);
            fid = fopen(path, 'wt');
            fwrite(fid, encodedTaskData, 'char');
            fclose(fid);

            delete(u);
            delete(cppf);
            
            edit (filepath);

            close(app.DataCleanerCustomFunctionDialogUIFigure);
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.DataCleanerCustomFunctionDialogUIFigure);
            delete(app);
        end

        % Value changing function: NameEditField
        function NameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            if strlength(changingValue) >= 3
                app.OKButton.Enable = true;
            else
                app.OKButton.Enable = false;
            end
            
            if exist(changingValue, "builtin") || ~isempty(which(changingValue))
                app.NameEditField.FontColor = 'blue';
                app.AddAFunctionLabel.FontColor = 'blue';
                app.AddAFunctionLabel.Text = getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:ADD_EXISTING_FCN_LABEL'));
                app.NameEditField.Tooltip = {getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:FCN_NAME_TT'));getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:EXISTING_FCN'))};
            else
                app.NameEditField.FontColor = 'black';
                app.AddAFunctionLabel.FontColor = 'black';
                app.AddAFunctionLabel.Text = getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:ADD_CUSTOM_FCN_LABEL'));
                app.NameEditField.Tooltip = {getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:FCN_NAME_TT'));getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:NEW_FCN'))};
            end
        end

        % Selection changed function: OperationTypeButtonGroup
        function OperationTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.OperationTypeButtonGroup.SelectedObject;
            if selectedButton == app.TableWideOperationButton
            app.SyntaxLabel.Text = ['[tableOut] = ' getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:FCN_NAME')) '(tableIn, <optArgs>...)'];
            app.WorkflowDescriptionLabel.Text = getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:WORKFLOW_DESCRIPTION_TABLE', 'inputTable'));
            else
                app.SyntaxLabel.Text = ['[outputTableColumn] = ' getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:FCN_NAME')) '(inputTableColumn, <optArgs>...)'];
            app.WorkflowDescriptionLabel.Text = getString(message('MATLAB:datatools:preprocessing:tasks:customPPdialog:WORKFLOW_DESCRIPTION_COLUMN', 'inputTableColumn'));
            end
        end

        % Value changed function: DescriptionEditField
        function DescriptionEditFieldValueChanged(app, event)
            value = app.DescriptionEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataCleanerCustomFunctionDialogUIFigure = uifigure('Visible', 'off');
            app.DataCleanerCustomFunctionDialogUIFigure.Name = 'Data Cleaner Custom Function Dialog';
            app.GridLayout = uigridlayout(app.DataCleanerCustomFunctionDialogUIFigure);
            app.AddCustomCleaningFunctionLabel = uilabel(app.GridLayout);
            app.AddCustomCleaningFunctionLabel.Text = 'Add Custom Cleaning Function';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.NameEditFieldLabel = uilabel(app.GridLayout2);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.GridLayout2, 'text');
            app.NameEditField.ValueChangingFcn = createCallbackFcn(app, @NameEditFieldValueChanging, true);
            app.NameEditField.Tooltip = {'Enter a new or existing function name'};
            app.DescriptionEditFieldLabel = uilabel(app.GridLayout2);
            app.DescriptionEditFieldLabel.Text = 'Description:';
            app.DescriptionEditField = uieditfield(app.GridLayout2, 'text');
            app.DescriptionEditField.ValueChangedFcn = createCallbackFcn(app, @DescriptionEditFieldValueChanged, true);
            app.DescriptionEditField.Tooltip = {'The tooltip that will display in the Data Cleaner when you hover over the button'};
            app.DescriptionEditField.Value = 'Performs custom cleanup';
            app.OperationTypeButtonGroup = uibuttongroup(app.GridLayout2);
            app.OperationTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OperationTypeButtonGroupSelectionChanged, true);
            app.OperationTypeButtonGroup.Title = 'Operation Type ';
            app.TableWideOperationButton = uiradiobutton(app.OperationTypeButtonGroup);
            app.TableWideOperationButton.Tooltip = {'The custom code will alter the entier table'};
            app.TableWideOperationButton.Text = 'Table-Wide Operation';
            app.TableWideOperationButton.Value = true;
            app.SingleColumnVariableOperationButton = uiradiobutton(app.OperationTypeButtonGroup);
            app.SingleColumnVariableOperationButton.Tooltip = {'The custom code will only affect one column variable'};
            app.SingleColumnVariableOperationButton.Text = 'Single Column Variable Operation';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.OKButton = uibutton(app.GridLayout3, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Enable = 'off';
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.GridLayout3, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AddAFunctionLabel = uilabel(app.GridLayout2);
            app.AddAFunctionLabel.Text = 'Add a custom function with the following syntax to the Data Cleaner.';
            app.SyntaxLabel = uilabel(app.GridLayout2);
            app.SyntaxLabel.Text = '[tableOut] = Name(tableIn, <optArgs>...)';
            app.WorkflowDescriptionLabel = uilabel(app.GridLayout2);
            app.WorkflowDescriptionLabel.Text = 'Data Cleaner will automatically pass the input table into the function. Optional input arguments may also be specified';
            % Show the figure after all components are created
            app.DataCleanerCustomFunctionDialogUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CustomFunctionDialog

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataCleanerCustomFunctionDialogUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataCleanerCustomFunctionDialogUIFigure)
        end
    end
end