classdef EditCustomFunctionsDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        CloseButton           matlab.ui.control.Button
        GridLayout2           matlab.ui.container.GridLayout
        EditFunctionButton    matlab.ui.control.Button
        DeleteFunctionButton  matlab.ui.control.Button
        EditCustomCleaningFunctionLabel  matlab.ui.control.Label
        UITable               matlab.ui.control.Table
    end

    
    properties (Access = private)
        UserAuthoredTasks
        SelectedRow
    end
    
    methods (Access = private)

        
        function resetTasksInToolstrip(app)            
            % Reset the tasks in the toolstrip
            atf = matlab.internal.preprocessingApp.tasks.AppTaskFactory.getInstance;
            atf.registerDefaults(true);

            if ~isempty(app.UserAuthoredTasks)
                % Reload the modified tasks to the toolstrip
                taskList = app.UserAuthoredTasks;
                for i = 1:length(taskList)
                    u = uifigure('Visible', false);
                    cppf = matlab.internal.preprocessingApp.tasks.CustomPPFunctionWrapper(u, "base", ...
                        "FunctionName", taskList(i).Name, 'HasTableVariable', taskList(i).HasTableVariable);
                    cppf.addTaskToFactory;
                    delete(u);
                end
            end
        end
        
        function saveTasksToDisk(app)
            path = fullfile(prefdir, 'Custom_Preprocessing', 'resources', 'customPreprocessing.json');
            
            if ~isempty(app.UserAuthoredTasks)
                % Save modified tasks to the disk as JSON;;
                encodedTaskData = jsonencode(app.UserAuthoredTasks);
                fid = fopen(path, 'wt');
                fwrite(fid, encodedTaskData, 'char');
                fclose(fid);
            else
                % delete the JSON file is all user authored tasks have been
                % deleted
                delete(path);
            end
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center the App
            ss = get(0, 'ScreenSize');
            app.UIFigure.Position = [(ss(3)-646)/2 (ss(4)-390)/2 646 390];
            
            % Internationalization
            app.UIFigure.Name = getString(message('MATLAB:datatools:preprocessing:tasks:editCustomPPdialog:DIALOG_TITLE'));
            app.EditCustomCleaningFunctionLabel.Text = getString(message('MATLAB:datatools:preprocessing:tasks:editCustomPPdialog:TITLE'));
            app.UITable.ColumnName{1} = getString(message('MATLAB:datatools:preprocessing:tasks:editCustomPPdialog:FILE_NAME'));
            app.UITable.ColumnName{2} = getString(message('MATLAB:datatools:preprocessing:tasks:editCustomPPdialog:FCN_NAME'));
            app.UITable.ColumnName{3} = getString(message('MATLAB:datatools:preprocessing:tasks:editCustomPPdialog:FCN_DESCRIPTION'));
            app.EditFunctionButton.Text = getString(message('MATLAB:datatools:preprocessing:tasks:editCustomPPdialog:EDIT_FCN_BTN'));
            app.DeleteFunctionButton.Text = getString(message('MATLAB:datatools:preprocessing:tasks:editCustomPPdialog:DELETE_FCN_BTN'));
            app.CloseButton.Text = getString(message('MATLAB:datatools:preprocessing:tasks:editCustomPPdialog:CLOSE_BTN'));

            % Set the UITable to use Row Selection
            app.UITable.SelectionType = 'row';
            
            % Read the user authored task metadata saved on disk
            path = fullfile(prefdir, 'Custom_Preprocessing', 'resources', 'customPreprocessing.json');
            tasks = jsondecode(fileread(path));
            app.UserAuthoredTasks = tasks;
            
            % Display the task metadata in a UITable
            tableData = struct2table(rmfield(tasks, "HasTableVariable"));
            tableData.Name = string(tableData.Name);
            tableData.Description = string(tableData.Description);
            
            app.UITable.Data = movevars(tableData, 'Name', 'After', 'Filename');
        end

        % Cell selection callback: UITable
        function handleSelection(app, event)
            indices = event.Indices;
            selectedRow = unique(indices(:,1));

            % Enable Edit and Delete Btn
            app.DeleteFunctionButton.Enable = 'on';
            app.EditFunctionButton.Enable = 'on';
            
            app.SelectedRow = selectedRow;
        end

        % Button pushed function: EditFunctionButton
        function EditFunctionButtonPushed(app, event)
            for i = 1:length(app.SelectedRow)
                path = fullfile(prefdir, 'Custom_Preprocessing', app.UserAuthoredTasks(app.SelectedRow(i)).Filename);
                edit(path);
            end
        end

        % Button pushed function: DeleteFunctionButton
        function DeleteFunctionButtonPushed(app, event)
            
            % Delete the selected UserAuthoredTasks
            for i = 1:length(app.SelectedRow)
                app.UserAuthoredTasks(app.SelectedRow(i)) = [];
            end
            
            % Reset the tasks in the toolstrip
            app.resetTasksInToolstrip();
            
            % Save modified tasks to disk
            app.saveTasksToDisk();
            
            % delete([path '/' app.SelectedTask.Name '.m']);
            
            % Update with the new table
            if ~isempty(app.UserAuthoredTasks)
                tableData = struct2table(rmfield(app.UserAuthoredTasks, "HasTableVariable"));
                tableData.Name = string(tableData.Name);
                tableData.Description = string(tableData.Name);
                app.UITable.Data = tableData;
            else
                app.UITable.Data = table.empty;
                % Disable Edit and Delete Btn
                app.DeleteFunctionButton.Enable = 'off';
                app.EditFunctionButton.Enable = 'off';
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            field = event.Indices(2);
            newData = event.NewData;

            if (field == 1)
                app.UserAuthoredTasks(app.SelectedRow).Name = newData;
            else
                app.UserAuthoredTasks(app.SelectedRow).Description = newData;
            end
            
            % Register the modified tasks to the gallery
            app.resetTasksInToolstrip();
            
            % Save modified tasks to disk
            app.saveTasksToDisk();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'File'; 'Function Name'; 'Description'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @handleSelection, true);
            app.EditCustomCleaningFunctionLabel = uilabel(app.GridLayout);
            app.EditCustomCleaningFunctionLabel.Text = 'Edit Custom Cleaning Function';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.DeleteFunctionButton = uibutton(app.GridLayout2, 'push');
            app.DeleteFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFunctionButtonPushed, true);
            app.DeleteFunctionButton.Enable = 'off';
            app.DeleteFunctionButton.Text = 'Delete Function';
            app.EditFunctionButton = uibutton(app.GridLayout2, 'push');
            app.EditFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @EditFunctionButtonPushed, true);
            app.EditFunctionButton.Enable = 'off';
            app.EditFunctionButton.Text = 'Edit Function';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EditCustomFunctionsDialog

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end