classdef ColumnTransformationDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ColumnTransformationUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        CommonOperationsDropDown      matlab.ui.control.DropDown
        CommonOperationsLabel         matlab.ui.control.Label
        PreviewDescLabel              matlab.ui.control.Label
        CodeDescLabel                 matlab.ui.control.Label
        ColumnVariableDescLabel       matlab.ui.control.Label
        TableNameDescLabel            matlab.ui.control.Label
        EqualsLabel                   matlab.ui.control.Label
        ColumnNameDropDown            matlab.ui.control.DropDown
        TableNameLabel                matlab.ui.control.Label
        PreviewColumnTable            matlab.ui.control.Table
        StatusLabel                   matlab.ui.control.Label
        CodeEditField                 matlab.ui.control.EditField
        CancelButton                  matlab.ui.control.Button
        OKButton                      matlab.ui.control.Button
    end

    % Copyright 2021 The MathWorks, Inc.

    properties(Constant)
        NEW_VARIABLE = "<" + getString(message('MATLAB:datatools:widgets:columnTranformationDialog:NEW_VARIABLE')) + ">";
    end
    
    properties (Access = private)
        TableNameI
        OriginalTableI % Original Table Passsed in
        SubsetHeightI = 1000
        
        UserCanceled (1,10) logical = false
    end
    
    properties
        OKCallbackFcn       function_handle = function_handle.empty
        CancelCallbackFcn   function_handle = function_handle.empty
    end
    
    properties(Dependent)
        Code
        TableName
        ColumnName
        OriginalTable
        SubsetHeight
    end 
    
    methods
        function c = get.Code(app)
            c = app.getLHS() + " = "...
                + app.CodeEditField.Value + ";";
        end
        function set.Code(app, c)
            code = strrep(c, app.getLHS() + " = ", "");
            app.CodeEditField.Value = code;
        end
        function tn = get.TableName(app)
            tn = string(app.TableNameI);
        end
        function set.TableName(app, tn)
            app.TableNameI = string(tn);
            app.TableNameLabel.Text = tn + ".";
            app.updatePreview;
        end
        function cn = get.ColumnName(app)
            cn = string(app.ColumnNameDropDown.Value);
        end
        function set.ColumnName(app, cn)
            if ~isempty(cn)
                if ~ismember(cn, app.ColumnNameDropDown.Items)
                    app.ColumnNameDropDown.Items{end+1} = char(cn);
                end
                app.ColumnNameDropDown.Value = cn;
                app.updatePreview;
            end
        end
        function t = get.OriginalTable(app)
            t = app.OriginalTableI;
        end
        function set.OriginalTable(app, t)
            app.OriginalTableI = t;
            app.ColumnNameDropDown.Items = [ ...
                app.OriginalTable.Properties.VariableNames, ...
                char(app.NEW_VARIABLE) ...
                ];
            app.updatePreview;
        end
        function t = get.SubsetHeight(app)
            t = app.SubsetHeightI;
        end
        function set.SubsetHeight(app, t)
            app.SubsetHeightI = t;
            app.updatePreview;
        end
    end
    
    methods(Access=public)
        function [code, outputVarName, canceled] = showDialog(app, NVPairs)
            arguments
                app
                NVPairs.TableName          string {mustBeScalarOrEmpty} = app.TableName
                NVPairs.ColumnName         string {mustBeScalarOrEmpty}  = app.ColumnName
                NVPairs.OriginalData       tabular = app.OriginalTable
                NVPairs.CenterXPosition    (1,1) double = -1
                NVPairs.CenterYPosition    (1,1) double = -1
                NVPairs.Block              (1,1) logical = true
                NVPairs.Code               string {mustBeScalarOrEmpty} = app.Code
            end

            canceled = false;
            ss = get(0, 'ScreenSize');
            dialogWidth = app.ColumnTransformationUIFigure.Position(3);
            dialogHeight = app.ColumnTransformationUIFigure.Position(4);
            if NVPairs.CenterXPosition<0 || NVPairs.CenterYPosition<0
                xPos = (ss(3)-dialogWidth)/2;
                yPos = (ss(4)-dialogHeight)/2;
            else
                xPos = NVPairs.CenterXPosition - (dialogWidth/2);
                yPos = NVPairs.CenterYPosition - (dialogHeight/2);
            end

            app.OriginalTable = NVPairs.OriginalData;
            app.TableName = NVPairs.TableName;
            app.ColumnName = NVPairs.ColumnName;
            
            % Reset Common Operations to Defaults State
            app.CommonOperationsDropDown.Value = app.CommonOperationsDropDown.ItemsData{1};

            code = NVPairs.Code;
            % Remove LHS from code
            if ~isempty(code) && strlength(strtrim(code)) >= 0
                code = strtrim(code);
                code = regexprep(code, ".*?=", "");
                code = regexprep(code, ";$", "");
                code = strtrim(code);
                app.CodeEditField.Value = code;
            else
                app.CodeEditField.Value = app.getLHS();
            end
            app.updatePreview;
            outputVarName = string.empty;

            % If already visible, just exit
            if app.ColumnTransformationUIFigure.Visible
                return;
            end
            app.ColumnTransformationUIFigure.Position(1) = xPos;
            app.ColumnTransformationUIFigure.Position(2) = yPos;
            
            app.UserCanceled = false;
            app.ColumnTransformationUIFigure.Visible = true;
            app.ColumnTransformationUIFigure.WindowStyle = 'modal';
            
            if NVPairs.Block
                waitfor(app.ColumnTransformationUIFigure, 'Visible', false);
            end

            canceled = app.UserCanceled;

            if ~app.UserCanceled
                code = app.Code;
                outputVarName = app.ColumnName;
            end
        end
    end
    
    methods (Access = private)
        function lhs = getLHS(app, columnName)
            arguments
                app
                columnName = app.ColumnName
            end
            tableName = app.TableName;
            if isempty(tableName)
                tableName = "";
            end
            if isvarname(columnName)
                lhs = tableName + "." + columnName;
            else
%                 lhs = internal.matlab.datatoolsservices.VariableUtils.generateDotSubscripting(app.TableName, columnName, app.OriginalTable);
%                 [~,~,columnName] = internal.matlab.datatoolsservices.FormatDataUtils.generateVariableNameAssignmentString(columnName, app.TableName, NaN);
                [~,~,columnName] = matlab.internal.tabular.generateVariableNameAssignmentString(app.OriginalTable, 1, columnName, tableName);
                lhs = tableName + ".(" + columnName + ")";
            end
        end

        function updatePreview(app, code)
            arguments
                app
                code = app.Code
            end
            try
                app.OKButton.Enable = true;
                app.PreviewColumnTable.ColumnName = app.ColumnName;
                app.StatusLabel.Text = "";
                app.StatusLabel.Tooltip = "";
                % Assign table locally
                h = min(height(app.OriginalTable), app.SubsetHeight);
                eval(sprintf("%s = app.OriginalTable(1:%d,:);", app.TableName, h));
                % Try to run the transfor code
                eval(code);
                app.PreviewColumnTable.Data = eval(sprintf("table(%s{:,'%s'}, 'VariableNames', {'%s'});", app.TableName, app.ColumnName, app.ColumnName));
            catch ME
                app.OKButton.Enable = false;
                app.StatusLabel.FontColor = "red";
                app.StatusLabel.Text = ME.message;
                app.StatusLabel.Tooltip = ME.message;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tableName, columnName, originalData, code, visible, xPos, yPos)
            arguments
                app
                tableName     string {mustBeScalarOrEmpty(tableName)} = "patients"
                columnName    string {mustBeScalarOrEmpty(columnName)} = "LastName"
                originalData  tabular = readtable('patients.dat')
                code          string {mustBeScalarOrEmpty(code)} = string.empty
                visible       (1,1) logical = true
                xPos          (1,1) double = -1
                yPos          (1,1) double = -1
            end

            % Internationalize app
            app.ColumnTransformationUIFigure.Name = getString(message('MATLAB:datatools:widgets:columnTranformationDialog:DIALOG_TITLE'));
            app.TableNameDescLabel.Text = getString(message('MATLAB:datatools:widgets:columnTranformationDialog:DIALOG_TABLE_NAME'));
            app.ColumnVariableDescLabel.Text = getString(message('MATLAB:datatools:widgets:columnTranformationDialog:DIALOG_COLUMN_VARIABLE'));
            app.CodeDescLabel.Text = getString(message('MATLAB:datatools:widgets:columnTranformationDialog:DIALOG_CODE'));
            app.PreviewDescLabel.Text = getString(message('MATLAB:datatools:widgets:columnTranformationDialog:DIALOG_PREVIEW'));
            app.OKButton.Text = getString(message('MATLAB:datatools:widgets:columnTranformationDialog:OK'));
            app.CancelButton.Text = getString(message('MATLAB:datatools:widgets:columnTranformationDialog:CANCEL'));
            app.CommonOperationsLabel.Text = getString(message('MATLAB:datatools:widgets:columnTranformationDialog:COMMON_OPERATIONS'));
            
            commonItems = {...
                getString(message('MATLAB:datatools:widgets:columnTranformationDialog:COMMON_OPS_CUSTOM')) ...
                ,getString(message('MATLAB:datatools:widgets:columnTranformationDialog:COMMON_OPS_LOG')) ...
                ,getString(message('MATLAB:datatools:widgets:columnTranformationDialog:COMMON_OPS_MULT')) ...
                ,getString(message('MATLAB:datatools:widgets:columnTranformationDialog:COMMON_OPS_ADD')) ...
                ,getString(message('MATLAB:datatools:widgets:columnTranformationDialog:COMMON_OPS_MAKE_DATETIME')) ...
                ,getString(message('MATLAB:datatools:widgets:columnTranformationDialog:COMMON_OPS_EXTRACT_TIME')) ...
                ,getString(message('MATLAB:datatools:widgets:columnTranformationDialog:COMMON_OPS_TO_UPPER')) ...
                ,getString(message('MATLAB:datatools:widgets:columnTranformationDialog:COMMON_OPS_TO_LOWER')) ...
                ,getString(message('MATLAB:datatools:widgets:columnTranformationDialog:COMMON_OPS_TRIM_STRING')) ...
                };
            commonItemsData = {...
                '$1' ...
                ,'log($1)' ...
                ,'$1 * 2' ...
                ,'$1 + $2' ...
                ,'$d1 + $d2' ...
                ,'timeofday($d1)' ...
                ,'upper($1)' ...
                ,'lower($1)' ...
                ,'strtrim($1)' ...
                };
            app.CommonOperationsDropDown.Items = commonItems;
            app.CommonOperationsDropDown.ItemsData = commonItemsData;

            app.ColumnTransformationUIFigure.Visible = visible;
            app.OriginalTable = originalData;
            if ~isempty(tableName)
                app.TableName = tableName;
            end
            if ~isempty(columnName)
                app.ColumnName = columnName;
            end
            
            % Remove LHS from code
            if ~isempty(code) && strlength(strtrim(code)) >= 0
                code = strtrim(code);
                code = regexprep(code, ".*?=", "");
                code = regexprep(code, ";$", "");
                app.CodeEditField.Value = code;
            else
                app.CodeEditField.Value = app.getLHS();
            end
            app.updatePreview;

            % Center dialog on screen
            ss = get(0, 'ScreenSize');
            p = app.ColumnTransformationUIFigure.Position;
            if xPos == -1
                xPos = (ss(3)-p(3))/2;
            end
            if xPos + p(3) > ss(3)
                xPos = ss(3) - p(3);
            end
            if yPos == -1
                yPos = (ss(4)-p(4))/2;
            end
            if yPos + p(4) > ss(4)
                yPos = ss(4) - p(4);
            end
            
            app.ColumnTransformationUIFigure.Position(1) = xPos;
            app.ColumnTransformationUIFigure.Position(2) = yPos;
        end

        % Value changing function: CodeEditField
        function CodeEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.updatePreview(app.getLHS() + "=" + changingValue + ";");
        end

        % Value changed function: CodeEditField
        function CodeEditFieldValueChanged(app, event)
            app.updatePreview;
        end

        % Value changed function: ColumnNameDropDown
        function ColumnNameDropDownValueChanged(app, event)
            if strcmp(event.Value, app.NEW_VARIABLE)
                app.ColumnNameDropDown.Value = genvarname(getString(message('MATLAB:datatools:widgets:columnTranformationDialog:NEW_VARIABLE')), app.OriginalTableI.Properties.VariableNames);
            elseif strcmp(strtrim(app.CodeEditField.Value), app.getLHS(event.PreviousValue)) && ismember(event.Value, app.OriginalTableI.Properties.VariableNames)
                app.CodeEditField.Value = app.getLHS(event.Value);
            end
            app.updatePreview;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.ColumnTransformationUIFigure.WindowStyle = 'normal';
            app.ColumnTransformationUIFigure.Visible = false;
            if ~isempty(app.OKCallbackFcn)
                try
                    app.OKCallbackFcn();
                catch ME
                    disp(ME.message);
                end
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.UserCanceled = true;
            app.ColumnTransformationUIFigure.WindowStyle = 'normal';
            app.ColumnTransformationUIFigure.Visible = false;
            if ~isempty(app.CancelCallbackFcn)
                try
                    app.CancelCallbackFcn();
                catch ME
                    disp(ME.message);
                end
            end
        end

        % Close request function: ColumnTransformationUIFigure
        function ColumnTransformationUIFigureCloseRequest(app, event)
            app.CancelButtonPushed(event);
        end

        % Value changed function: CommonOperationsDropDown
        function CommonOperationsDropDownValueChanged(app, event)
            code = app.CommonOperationsDropDown.Value;

            var1 =  app.getLHS(app.ColumnName);
            var1Index = find(strcmp(app.ColumnName, app.OriginalTable.Properties.VariableNames));
            if isempty(var1Index)
                var1 = app.getLHS(app.OriginalTable.Properties.VariableNames{1});
                var1Index = 1;
            end
            var1Class = class(app.OriginalTable.(var1Index));

            % Find next variable with matching class
            var2Index = [];
            if var1Index < width(app.OriginalTable)
                var2Index = find(varfun(@(v)strcmp(class(v), var1Class), app.OriginalTable(:,var1Index+1:end), 'OutputFormat', 'uniform'));
                if length(var2Index) > 1
                    var2Index = var2Index(1);
                end
                if ~isempty(var2Index)
                    var2Index = var1Index + var2Index;
                end
            end

            % If we still don't have a variable with matching class find
            % first variable with matching class
            if isempty(var2Index)
                var2Index = find(varfun(@(v)strcmp(class(v), var1Class), app.OriginalTable, 'OutputFormat', 'uniform'));
                if length(var2Index) > 1
                    var2Index = var2Index(1);
                end
            end

            % If we still don't have a match, just use the first variable
            if isempty(var2Index)
                var2Index = var1Index;
            end

            var2 = app.OriginalTable.Properties.VariableNames{var2Index};
            var2 = app.getLHS(var2);

            % Find datetime variables
            dtvar1 = var1;
            dtvar2 = var2;
            if ~ismember(var1Class, {'datetime', 'duration'})
                dtIndexes = find(varfun(@(v)isdatetime(v)||isduration(v), app.OriginalTable, 'OutputFormat', 'uniform'));
                if ~isempty(dtIndexes)
                    dtvar1 = app.OriginalTable.Properties.VariableNames{dtIndexes(1)};
                    dtvar1 = app.getLHS(dtvar1);
                    if length(dtIndexes) > 1
                        dtvar2 = app.OriginalTable.Properties.VariableNames{dtIndexes(2)};
                        dtvar2 = app.getLHS(dtvar2);
                    else
                        dtvar2 = dtvar1;
                    end
                else
                    dtvar1 = var1;
                    dtvar2 = var2;
                end
            end

            % Replace variables in code
            code = strrep(code, '$1', var1);
            code = strrep(code, '$2', var2);
            code = strrep(code, '$d1', dtvar1);
            code = strrep(code, '$d2', dtvar2);
            
            app.CodeEditField.Value = code;
            app.updatePreview;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ColumnTransformationUIFigure = uifigure('Visible', 'off');
            app.ColumnTransformationUIFigure.Name = 'Column Transformation';
            app.ColumnTransformationUIFigure.CloseRequestFcn = createCallbackFcn(app, @ColumnTransformationUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.ColumnTransformationUIFigure);
            app.OKButton = uibutton(app.GridLayout, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Tag = 'CTD_OK_BUTTON';
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tag = 'CTD_CANCEL_BUTTON';
            app.CancelButton.Text = 'Cancel';
            app.CodeEditField = uieditfield(app.GridLayout, 'text');
            app.CodeEditField.ValueChangedFcn = createCallbackFcn(app, @CodeEditFieldValueChanged, true);
            app.CodeEditField.ValueChangingFcn = createCallbackFcn(app, @CodeEditFieldValueChanging, true);
            app.CodeEditField.Tag = 'CTD_CODE_EDITFILED';
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Tag = 'CTD_ERROR_LABEL';
            app.StatusLabel.Text = '';
            app.PreviewColumnTable = uitable(app.GridLayout);
            app.PreviewColumnTable.ColumnName = '';
            app.PreviewColumnTable.RowName = {};
            app.PreviewColumnTable.Tag = 'CTD_PREVIEW_TABLE';
            app.TableNameLabel = uilabel(app.GridLayout);
            app.TableNameLabel.Tag = 'CTD_TABLENAME_LABEL';
            app.TableNameLabel.Text = 'TableName.';
            app.ColumnNameDropDown = uidropdown(app.GridLayout);
            app.ColumnNameDropDown.Items = {'ColumnName'};
            app.ColumnNameDropDown.Editable = 'on';
            app.ColumnNameDropDown.ValueChangedFcn = createCallbackFcn(app, @ColumnNameDropDownValueChanged, true);
            app.ColumnNameDropDown.Tag = 'CTD_VARIABLE_DROPDOWN';
            app.ColumnNameDropDown.Value = 'ColumnName';
            app.EqualsLabel = uilabel(app.GridLayout);
            app.EqualsLabel.Text = '=';
            app.TableNameDescLabel = uilabel(app.GridLayout);
            app.TableNameDescLabel.Text = 'Table Name:';
            app.ColumnVariableDescLabel = uilabel(app.GridLayout);
            app.ColumnVariableDescLabel.Text = 'Column Variable:';
            app.CodeDescLabel = uilabel(app.GridLayout);
            app.CodeDescLabel.Text = 'Code:';
            app.PreviewDescLabel = uilabel(app.GridLayout);
            app.PreviewDescLabel.Text = 'Preview:';
            app.CommonOperationsLabel = uilabel(app.GridLayout);
            app.CommonOperationsLabel.Text = 'Common Operations:';
            app.CommonOperationsDropDown = uidropdown(app.GridLayout);
            app.CommonOperationsDropDown.Items = {'Take the Log', 'Multiply By 2', 'Add two Columns', 'Extract Date Portion from Datetime', 'Extract Time Portion from Datetime', 'Combine Date and Time into Datetime'};
            app.CommonOperationsDropDown.ValueChangedFcn = createCallbackFcn(app, @CommonOperationsDropDownValueChanged, true);
            app.CommonOperationsDropDown.Tag = 'CTD_COMMON_OPS_DROPDOWN';
            app.CommonOperationsDropDown.Value = 'Take the Log';
            % Show the figure after all components are created
            app.ColumnTransformationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColumnTransformationDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ColumnTransformationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ColumnTransformationUIFigure)
        end
    end
end