classdef borrowLicenses < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MathWorksLicenseBorrowingUIFigure  matlab.ui.Figure
        TextArea                        matlab.ui.control.TextArea
        CloseButton                     matlab.ui.control.Button
        SpecifyBorrowPeriodPanel        matlab.ui.container.Panel
        UntilendofLabel                 matlab.ui.control.Label
        TurnOffAutoBorrowButton         matlab.ui.control.Button
        TurnOnAutoBorrowButton          matlab.ui.control.Button
        NumberofdaystoborrowSpinner     matlab.ui.control.Spinner
        NumberofdaystoborrowSpinnerLabel  matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        ReturnselectedproductsIfyouarecurrentlyusingtheproductLabel  matlab.ui.control.Label
        YouselectproductstoborrowLabel  matlab.ui.control.Label
        BorrowsallproductscurrentlyinuseLabel  matlab.ui.control.Label
        ReturnborrowedproductsearlyButton  matlab.ui.control.RadioButton
        BorrowproductsautomaticallyasyouworkwiththemButton  matlab.ui.control.RadioButton
        BorrowselectedproductsbychoosingfromalistButton  matlab.ui.control.RadioButton
        ProductStatusPanel              matlab.ui.container.Panel
        ReturnButton                    matlab.ui.control.Button
        BorrowButton                    matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
    end


    properties (Access = private, Constant = true)
        STATUS_INDIVIDUAL = 1;
        STATUS_BORROWED = 4;
        INDIVIDUAL_STRING_VALUE = string(getString(message("MATLAB:licenseborrow:individual")));
        BORROW_STRING_VALUE = string(getString(message("MATLAB:licenseborrow:borrowed")));
    end

    methods (Access = private)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Refresh table content after borrow or return  %
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function [productTable, borrowedTable, featuresTable, borrowedfeatures] = RefreshTables(app)

            % Create Product List
            productList = matlab.internal.licensing.getAllBorrowingInfo;
            % Add some fields here to keep the struct consistent instead of
            % adding them later.
            [productList.productname] = deal('');
            [productList.select] = deal(false);
            [productList.statusString] = deal("");
            [productList.inuseString] = deal("");

            % Remove any duplicates.
            productList = removeDuplicateEntries(app, productList);

            % Set the product names
            productList = populateProductNames(app, productList);

            % Sort the list
            productList = sortList(app, productList);

            % Set borrow status
            [productList([productList.status] == app.STATUS_INDIVIDUAL).statusString] = deal(app.INDIVIDUAL_STRING_VALUE);
            [productList([productList.status] == app.STATUS_BORROWED).statusString] = deal(app.BORROW_STRING_VALUE);

            % Set inuse status
            [productList([productList.inuse]).inuseString] = deal(getString(message("MATLAB:licenseborrow:yesLabel")));

            % Create table from structure
            productTable = struct2table(productList,'AsArray',true);

            % Reorder columns
            featuresTable = productTable(:,{'feature','statusString'});
            productTable = productTable(:,{'select' 'productname' 'inuseString' 'statusString' 'returnDate'});
            productTable.Properties.VariableNames = {'Select','Product_Name','In_Use','Status','Return_Date'};

            % Format dates and fill empty dates with blank
            productTable.Return_Date = string(datetime(productTable.Return_Date,"Format","MMM dd, yyyy", ...
                "InputFormat","yyyy-MM-dd HH:mm:ss"));
            productTable = fillmissing(productTable,"constant","", "DataVariables", @isstring);

            % Create borrowed table for returning licenses
            borrowedTable = productTable(productTable.Status == app.BORROW_STRING_VALUE, : );
            borrowedTable{:,'Select'} = true;
            borrowedfeatures = featuresTable(featuresTable.statusString == app.BORROW_STRING_VALUE, 'feature' );

        end

        % Remove any duplicates.  First keep the ones that are borrowed, then inuse.
        function results = removeDuplicateEntries(app, inputStruct)
            results = repmat(inputStruct,0,0);

            % Get unique names
            fNames = unique({inputStruct(:).feature});

            % If count is same as length of struct then no dups so just
            % return early.
            if (length(fNames) == length(inputStruct))
                results = inputStruct;
            else
                % If there are dups, loop over list of names to find and
                % address duplicates
                for ind = 1:length(fNames)
                    % find dups of this feature (if any)
                    currentElements = inputStruct(strcmp({inputStruct.feature}, fNames{ind}));
                    if length(currentElements) == 1
                        % Found just one so use it
                        results(end+1,1) = currentElements; %#ok<AGROW>
                    else
                        % Found multiples for first find one that is
                        % borrowed, then check for one that is inuse, else take
                        % what is left
                        elementToKeep = currentElements([currentElements.status] == app.STATUS_BORROWED);
                        if isempty(elementToKeep)
                            elementToKeep = currentElements([currentElements.inuse]);
                            if isempty(elementToKeep)
                                % Just keep the first one
                                elementToKeep = currentElements(1);
                            else
                                % Keep the inuse one
                                elementToKeep = inuseElement;
                            end
                        end
                        results(end+1,1) = elementToKeep; %#ok<AGROW>
                    end
                end
            end

        end


        % Add the product names to the struct.  Remove any items that do
        % not have names.
        function results = populateProductNames(app, prodsOnLicense)
            results = repmat(prodsOnLicense,0,0);

            installedAndLicensed = getProductNames(app); %list of installed names

            % Find all the instances where the feature name matches so that
            % we can get the product name.  If we can't find the feature
            % name it means we are not licensed for it or it isn't
            % installed, so we won't return that data.
            featureListToSearch = {installedAndLicensed(:).feature};
            for ind = 1:length(prodsOnLicense)
                [~,fIndex] = ismember(prodsOnLicense(ind).feature, featureListToSearch);
                if (fIndex ~= 0)
                    % if the feature name was found in prodsOnLicense and
                    % the installedAndLicensed list, then we found the product
                    % name and we know it is installed and licensed.
                    results(end+1,1) = prodsOnLicense(ind); %#ok<AGROW>
                    results(end,1).productname = installedAndLicensed(fIndex).productname;
                end
            end
        end

        % Sort the list by product name.  MATLAB and SIMULINK first, then alphabetical
        % This expects a struct with unique product names (i.e. has be
        % deduped).
        function results = sortList(app, inputStruct)
            % Find MATLAB and Simulink and stash them away.  Then remove
            % those elements from the struct before sorting.  This should
            % all handle the cases where the strings are not found and
            % empty is returned.
            pnamesList = {inputStruct.productname};
            mlIndex = find(strcmp(pnamesList,'MATLAB'));
            mlElement = inputStruct(mlIndex);
            slIndex = find(strcmp(pnamesList,'Simulink'));
            slElement = inputStruct(slIndex);
            inputStruct([mlIndex slIndex]) = [];

            % Sort what is left, then add MATLAB and Simulink back in.
            [~,idx]=sort({inputStruct.productname});
            results = [mlElement; slElement; inputStruct(idx)];
        end

        % Get the list of product names and basecodes that are currently
        % installed.
        % This reads output of VER command and filters out products that do
        % not have a basecode.
        function realProductNames = getProductNames(app)
            persistent realProductNamesStruct;
            if isempty(realProductNamesStruct)
                realProductNamesStruct = struct('productname',{}, 'feature',{});
                verInfo = ver;

                for tmpIndex = 1:length(verInfo)
                    aName = verInfo(tmpIndex).Name;
                    basecode = matlab.internal.product.getBaseCodeFromProductName(aName);
                    featurenameStr = matlab.internal.product.getFeatureNameFromBaseCode(basecode);
                    if (~isempty(featurenameStr))
                        realProductNamesStruct(end+1).productname = aName; %#ok<AGROW>
                        realProductNamesStruct(end).feature = char(lower(featurenameStr));
                    end
                end

            end
            realProductNames = realProductNamesStruct;
        end

        function results = isAutoBorrowingOn(app)
            results = settings().matlab.licensing.AutoBorrowEnabled.ActiveValue;
        end

        function setAutoBorrowing(app, value)
            s = settings;
            s.matlab.licensing.AutoBorrowEnabled.TemporaryValue = value;
        end

        function datestr = getDaysSpinnerLabel(app, theDate)
            messageobj = message("MATLAB:licenseborrow:untilDateLabel", string(theDate));
            datestr = getString(messageobj);
        end

        function results = hasBorrowedInThisSession(app)
            results = settings().matlab.licensing.BorrowedInCurrentSession.ActiveValue;
        end

        function setBorrowedThisSession(app)
            s = settings;
            s.matlab.licensing.BorrowedInCurrentSession.TemporaryValue = true;
        end
        
        function populateTextFields(app)
            app.MathWorksLicenseBorrowingUIFigure.Name = getString(message("MATLAB:licenseborrow:appFigureName"));
            app.ProductStatusPanel.Title = getString(message("MATLAB:licenseborrow:prodStatus"));
            app.UITable.ColumnName = {""; getString(message("MATLAB:licenseborrow:prodColumnHeading")); ...
                getString(message("MATLAB:licenseborrow:inuseColumnHeading"));...
                getString(message("MATLAB:licenseborrow:statusColumnHeading")); ...
                getString(message("MATLAB:licenseborrow:dateColumnHeading"))};
            app.BorrowButton.Text =  getString(message("MATLAB:licenseborrow:borrowButton"));
            app.ReturnButton.Text = getString(message("MATLAB:licenseborrow:returnButton"));
            app.BorrowselectedproductsbychoosingfromalistButton.Text = getString(message("MATLAB:licenseborrow:fromListRadioLabel"));
            app.BorrowproductsautomaticallyasyouworkwiththemButton.Text = getString(message("MATLAB:licenseborrow:autoRadioLabel"));
            app.ReturnborrowedproductsearlyButton.Text = getString(message("MATLAB:licenseborrow:returnRadioLabel"));
            app.BorrowsallproductscurrentlyinuseLabel.Text = getString(message("MATLAB:licenseborrow:autoRadioText"));
            app.YouselectproductstoborrowLabel.Text = getString(message("MATLAB:licenseborrow:fromListRadioText"));
            app.ReturnselectedproductsIfyouarecurrentlyusingtheproductLabel.Text = getString(message("MATLAB:licenseborrow:returnRadioText"));
            app.SpecifyBorrowPeriodPanel.Title = getString(message("MATLAB:licenseborrow:borrowPeriodLabel"));
            app.NumberofdaystoborrowSpinnerLabel.Text = getString(message("MATLAB:licenseborrow:borrowDateSpinnerLabel"));
            app.TurnOnAutoBorrowButton.Text = getString(message("MATLAB:licenseborrow:autoOnButton"));
            app.TurnOffAutoBorrowButton.Text = getString(message("MATLAB:licenseborrow:autoOffButton"));
            app.CloseButton.Text = getString(message("MATLAB:licenseborrow:closeButton"));
            app.TextArea.Value = getString(message("MATLAB:licenseborrow:autoBorrowText"));
        end
        
        % Convert a datetime object (which uses the local locale) to an
        % en_US format that is required for license borrowing. 
        % This only returns the year month and day in dd-mmm-yyyy format.
        % Input is a datetime object in local time.
        % Returns a string in en_US format (uses the lower case U.S. month).
        function enUSDate = convertLocalTimeToUSTime(~, localDatetime)
            [y, m, d] = ymd(localDatetime);
            dNumber = datenum(y,m,d);
            enUSDate = lower(datestr(dNumber,'en_US'));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            populateTextFields(app);

            % Borrowing UI default values

            borrowingLimitDays = matlab.internal.licensing.getMaxBorrowHours / 24;
            defaultBorrowDays = 10;
            mlInfo = matlab.internal.licensing.getFeatureInfo('ML');

            if  ~isempty(mlInfo)
                mlexpDate = mlInfo(1).expdate;
                % Search for date containing 1-jan-0 instead of the
                % full string 01-jan-0000 because the date can be returned
                % in different formats.  This covers 01-jan-0000, 01-jan-0,
                % 1-jan-0, 1-jan-0000.
                if (~contains(mlexpDate, '1-jan-0','IgnoreCase', true))
                    % +1 since datetime is at beginning of day, we need end.
                    daydifference = days(datetime(mlexpDate,'Locale','en_US') - datetime("today")) + 1;

                    if (daydifference < borrowingLimitDays)
                        borrowingLimitDays = daydifference;
                    end
                end
            end

            % Need a minimum of 2 for the daysToBorrow spinner.
            if (borrowingLimitDays < 2)
                borrowingLimitDays = 2;
            end
            % Adjust default borrow days to borrow limit if necessary
            if (defaultBorrowDays > borrowingLimitDays)
                defaultBorrowDays = borrowingLimitDays;
            end

            % Set some spinner properties
            app.NumberofdaystoborrowSpinner.Limits = [1 borrowingLimitDays];
            app.NumberofdaystoborrowSpinner.Value = defaultBorrowDays;
            app.UntilendofLabel.Text = app.getDaysSpinnerLabel(datetime('today') + caldays(defaultBorrowDays - 1));

            % Get table data
            [productTable, borrowedTable, ~, ~] = RefreshTables(app);

            % Enable/disable some controls based on borrow mode and state.
            if app.isAutoBorrowingOn
                % Disable turn on auto borrow and enable turn off auto borrow button
                app.TurnOnAutoBorrowButton.Enable = false;
                app.TurnOffAutoBorrowButton.Enable = true;
                app.TurnOnAutoBorrowButton.Visible = true;
                app.TurnOffAutoBorrowButton.Visible = true;
                app.BorrowproductsautomaticallyasyouworkwiththemButton.Value = true;

                % Disable other radio buttons
                app.BorrowselectedproductsbychoosingfromalistButton.Enable = false;
                app.YouselectproductstoborrowLabel.Enable = false;
                app.ReturnborrowedproductsearlyButton.Enable = false;
                app.ReturnselectedproductsIfyouarecurrentlyusingtheproductLabel.Enable = false;

                % Show Auto Borrow confirmation
                app.TextArea.Visible = true;
                app.ProductStatusPanel.Visible = false;
            else
                app.TurnOnAutoBorrowButton.Visible = false;
                app.TurnOffAutoBorrowButton.Visible = false;
            end

            if isempty(borrowedTable)
                % Nothing is borrowed so don't enable early return button.
                app.ReturnborrowedproductsearlyButton.Enable = false;
                app.ReturnselectedproductsIfyouarecurrentlyusingtheproductLabel.Enable = false;
            end

            app.UITable.ColumnEditable = [true false false false false false];
            app.UITable.Data = (productTable);
            app.BorrowButton.Enable = false;
            app.BorrowButton.Visible = true;
            app.ReturnButton.Visible = false;
            app.SpecifyBorrowPeriodPanel.Visible = true;

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            [productTable, borrowedTable, ~, ~] = RefreshTables(app);

            % Update screen from borrowing selection
            if app.BorrowselectedproductsbychoosingfromalistButton.Value
                app.TurnOnAutoBorrowButton.Visible = false;
                app.TurnOffAutoBorrowButton.Visible = false;
                app.UITable.ColumnEditable = [true false false false false false];
                app.BorrowButton.Visible = true;
                app.UITable.Data = (productTable);
                app.ReturnButton.Visible = false;
                app.SpecifyBorrowPeriodPanel.Visible = true;
                app.BorrowButton.Enable = false;
            elseif app.BorrowproductsautomaticallyasyouworkwiththemButton.Value
                app.TurnOnAutoBorrowButton.Visible = true;
                app.TurnOffAutoBorrowButton.Visible = true;
                app.UITable.ColumnEditable = [false false false false false false];
                app.BorrowButton.Visible = false;
                app.UITable.Data = (productTable);
                app.ReturnButton.Visible = false;
                app.SpecifyBorrowPeriodPanel.Visible = true;
            elseif app.ReturnborrowedproductsearlyButton.Value
                app.TurnOnAutoBorrowButton.Visible = false;
                app.TurnOffAutoBorrowButton.Visible = false;
                app.UITable.ColumnEditable = [true false false false false false];
                app.BorrowButton.Visible = false;
                app.UITable.Data = (borrowedTable);
                app.ReturnButton.Enable = true;
                app.ReturnButton.Visible = true;
                app.SpecifyBorrowPeriodPanel.Visible = false;
            end
        end

        % Value changed function: NumberofdaystoborrowSpinner
        function NumberofdaystoborrowSpinnerValueChanged(app, event)
            numberOfDays = app.NumberofdaystoborrowSpinner.Value - 1 ;
            % Update borrowing expiration date from number of days
            app.UntilendofLabel.Text = app.getDaysSpinnerLabel(datetime('today') + caldays(numberOfDays));
        end

        % Button pushed function: TurnOnAutoBorrowButton
        function TurnOnAutoBorrowButtonPushed(app, event)
            try
                numberOfDays = app.NumberofdaystoborrowSpinner.Value - 1;
                localdate = datetime('today','Format','dd-MMM-uuuu') + caldays(numberOfDays);
                enUsDate = app.convertLocalTimeToUSTime(localdate);
                matlab.internal.licensing.autoBorrowModeOn(enUsDate);
            catch ME
                % Issue error if auto borrow failed, and return early
                % without changing all the other UI elements.
                msgTitle = 'Error enabling Auto Borrow mode';
                uialert(app.MathWorksLicenseBorrowingUIFigure, ME.message, msgTitle, 'Icon','error' );
                return;
            end
            % Auto borrowing was succesfully enabled.  Update the UI.
            app.setAutoBorrowing(true);
            % Disable turn on auto borrow and enable turn off auto borrow button
            app.TurnOnAutoBorrowButton.Enable = false;
            app.TurnOffAutoBorrowButton.Enable = true;
            % Disable other radio buttons
            app.BorrowselectedproductsbychoosingfromalistButton.Enable = false;
            app.YouselectproductstoborrowLabel.Enable = false;
            app.ReturnborrowedproductsearlyButton.Enable = false;
            app.ReturnselectedproductsIfyouarecurrentlyusingtheproductLabel.Enable = false;

            % Change panel visibility
            app.TextArea.Visible = true;
            app.ProductStatusPanel.Visible = false;

        end

        % Button pushed function: TurnOffAutoBorrowButton
        function TurnOffAutoBorrowButtonPushed(app, event)

            % Enable other radio buttons and disable turn off borrowing button
            app.TurnOnAutoBorrowButton.Enable = true;
            app.TurnOffAutoBorrowButton.Enable = false;
            app.BorrowselectedproductsbychoosingfromalistButton.Enable = true;
            app.YouselectproductstoborrowLabel.Enable = true;

            % Turn off auto borrow and show confirmation
            matlab.internal.licensing.autoBorrowModeOff;
            app.TextArea.Visible = false;
            app.ProductStatusPanel.Visible = true;

            %Refresh tables and set autoBorrow off
            [productTable, ~, ~, borrowedFeatures] = RefreshTables(app);
            app.UITable.Data = (productTable);
            app.setAutoBorrowing(false);

            % Display popup message
            messageobj = message("MATLAB:licenseborrow:borrowOffDlgMsg");
            dlgMessage = getString(messageobj);
            messagetitleobj = message("MATLAB:licenseborrow:borrowOffDlgTitle");
            dlgTitle = getString(messagetitleobj);
            uialert(app.MathWorksLicenseBorrowingUIFigure, dlgMessage, dlgTitle,'Icon','success');

            % Enable borrow return button, and lock the return function for
            % this session.
            if ~isempty(borrowedFeatures)
                app.setBorrowedThisSession;
                app.ReturnborrowedproductsearlyButton.Enable = true;
                app.ReturnselectedproductsIfyouarecurrentlyusingtheproductLabel.Enable = true;
            end

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app.MathWorksLicenseBorrowingUIFigure)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            if event.NewData
                % This needs to be aware of which list is being selected.
                % When borrowing we need to disallow non-borrowable
                % products.  But when returning we need to allow them.
                if ~app.ReturnButton.Visible
                    indice = event.Indices(1);
                    statusValue = app.UITable.Data.Status(indice);
                    if statusValue ~= ""
                        app.UITable.Data.Select(indice) = 0;
                    else
                        app.BorrowButton.Enable = true;
                    end
                end
            end
            % If on the return panel, enable/disable the Return button
            if (app.ReturnButton.Visible)
                % if anything is selected enable Return button
                if (any(app.UITable.Data.Select))
                    app.ReturnButton.Enable = "on";
                else
                    app.ReturnButton.Enable = "off";
                end
            end
        end

        % Button pushed function: BorrowButton
        function BorrowButtonPushed(app, event)

            [~, ~, featuresTable, ~] = RefreshTables(app);
            % Items selected for borrowing. Copy table, merge with features and filter by selected
            borrowingList = app.UITable.DisplayData;
            borrowingList = [borrowingList featuresTable];
            borrowingList = borrowingList(borrowingList.Select,:);
            [borrowingcount,~] = size(borrowingList);
            borrowedAtLeastOneProd = false;
            % Collect number of days from spinner and set expire days
            numberOfDays = app.NumberofdaystoborrowSpinner.Value - 1;
            localdate = datetime('today','Format','dd-MMM-uuuu') + caldays(numberOfDays);
            enUsDate = app.convertLocalTimeToUSTime(localdate);
            borrowExpire = [enUsDate ' 23:59:00'];
            for i = (1:borrowingcount)
                featureNameToBorrow = char(table2cell(borrowingList(i,'feature')));
                try
                    matlab.internal.licensing.borrowFeature(featureNameToBorrow, borrowExpire);
                    borrowedAtLeastOneProd = true;
                catch ME
                    % Issue dialog and exit loop
                    productNameToBorrow = char(table2cell(borrowingList(i,'Product_Name')));
                    errMessage = getString(message("MATLAB:licenseborrow:borrowErrorMsg", productNameToBorrow, ME.message));
                    errTitle = getString(message("MATLAB:licenseborrow:borrowErrorTitle"));
                    uialert(app.MathWorksLicenseBorrowingUIFigure, errMessage, errTitle, 'Icon','error' );
                    break;
                end
            end
            [productTable, ~, ~, ~] = RefreshTables(app);
            app.UITable.Data = (productTable);

            % Lock return function and enable return button.
            % Only do these if any borrow operation was successful.
            if borrowedAtLeastOneProd
                app.setBorrowedThisSession;
                app.ReturnborrowedproductsearlyButton.Enable = true;
                app.ReturnselectedproductsIfyouarecurrentlyusingtheproductLabel.Enable = true;
            end
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            if app.hasBorrowedInThisSession
                % Check if borrowing has been initiated in this session.
                % If it has then issue dialog to inform the user that MATLAB
                % must be restarted in order to return borrowed keys.
                messageobj = message("MATLAB:licenseborrow:returnWarnMsg");
                returnMessage = getString(messageobj);
                messagetitleobj = message("MATLAB:licenseborrow:returnWarnTitle");
                returnTitle = getString(messagetitleobj);
                uialert(app.MathWorksLicenseBorrowingUIFigure, returnMessage, returnTitle, 'Icon', 'warning');
            else
                [~, ~, ~, borrowedfeatures] = RefreshTables(app);
                borrowedTable = [app.UITable.DisplayData borrowedfeatures];
                returningList = borrowedTable(borrowedTable.Select,:);
                [returningcount,~] = size(returningList);

                for i = (1:returningcount)
                    try
                        matlab.internal.licensing.returnBorrowedFeature(char(table2cell(returningList(i,'feature'))));
                    catch ME
                        % Issue error dialog
                        productNameToReturn = char(table2cell(returningList(i,'Product_Name')));
                        errMessage = getString(message("MATLAB:licenseborrow:returnErrorMsg", productNameToReturn, ME.message));
                        errTitle = getString(message("MATLAB:licenseborrow:returnErrorTitle"));
                        uialert(app.MathWorksLicenseBorrowingUIFigure, errMessage, errTitle, 'Icon','error' );
                    end
                end
                [~, borrowedTable, ~, ~] = RefreshTables(app);
                app.UITable.Data=borrowedTable;
                if isempty(borrowedTable)
                    app.ReturnborrowedproductsearlyButton.Enable = false;
                    app.ReturnselectedproductsIfyouarecurrentlyusingtheproductLabel.Enable = false;
                    app.ReturnButton.Enable = false;
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MathWorksLicenseBorrowingUIFigure = uifigure('Visible', 'off');
            app.MathWorksLicenseBorrowingUIFigure.Name = 'MathWorks License Borrowing';
            app.MathWorksLicenseBorrowingUIFigure.Tag = 'mainFigure';
            app.ProductStatusPanel = uipanel(app.MathWorksLicenseBorrowingUIFigure);
            app.ProductStatusPanel.Title = 'Product Status';
            app.ProductStatusPanel.Tag = 'statusPanel';
            app.UITable = uitable(app.ProductStatusPanel);
            app.UITable.ColumnName = {''; 'Product Name'; 'In Use'; 'Borrow Status'; 'Return Date'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.Tag = 'productTable';
            app.BorrowButton = uibutton(app.ProductStatusPanel, 'push');
            app.BorrowButton.ButtonPushedFcn = createCallbackFcn(app, @BorrowButtonPushed, true);
            app.BorrowButton.Tag = 'borrowButton';
            app.BorrowButton.Enable = 'off';
            app.BorrowButton.Visible = 'off';
            app.BorrowButton.Text = 'Borrow';
            app.ReturnButton = uibutton(app.ProductStatusPanel, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Tag = 'returnButton';
            app.ReturnButton.Visible = 'off';
            app.ReturnButton.Text = 'Return';
            app.ButtonGroup = uibuttongroup(app.MathWorksLicenseBorrowingUIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Tag = 'rbButtonGroup';
            app.BorrowselectedproductsbychoosingfromalistButton = uiradiobutton(app.ButtonGroup);
            app.BorrowselectedproductsbychoosingfromalistButton.Tag = 'selectProductsRB';
            app.BorrowselectedproductsbychoosingfromalistButton.Text = 'Borrow selected products by choosing from a list.';
            app.BorrowselectedproductsbychoosingfromalistButton.Value = true;
            app.BorrowproductsautomaticallyasyouworkwiththemButton = uiradiobutton(app.ButtonGroup);
            app.BorrowproductsautomaticallyasyouworkwiththemButton.Tag = 'autoBorrowRB';
            app.BorrowproductsautomaticallyasyouworkwiththemButton.Text = 'Borrow products automatically as you work with them';
            app.ReturnborrowedproductsearlyButton = uiradiobutton(app.ButtonGroup);
            app.ReturnborrowedproductsearlyButton.Tag = 'returnProductsRB';
            app.ReturnborrowedproductsearlyButton.Text = 'Return borrowed products early';
            app.BorrowsallproductscurrentlyinuseLabel = uilabel(app.ButtonGroup);
            app.BorrowsallproductscurrentlyinuseLabel.Tag = 'autoBorrowLabel';
            app.BorrowsallproductscurrentlyinuseLabel.Text = 'Borrows all products currently in use, plus continue to borrow products automatically as you work with them';
            app.YouselectproductstoborrowLabel = uilabel(app.ButtonGroup);
            app.YouselectproductstoborrowLabel.Tag = 'selectProductsLabel';
            app.YouselectproductstoborrowLabel.Text = 'You select products to borrow. Only products you select will be borrowed';
            app.ReturnselectedproductsIfyouarecurrentlyusingtheproductLabel = uilabel(app.ButtonGroup);
            app.ReturnselectedproductsIfyouarecurrentlyusingtheproductLabel.Tag = 'returnProductsLabel';
            app.ReturnselectedproductsIfyouarecurrentlyusingtheproductLabel.Text = 'Return selected products. If you are currently using the products they will not check-in  until you close MATLAB.';
            app.SpecifyBorrowPeriodPanel = uipanel(app.MathWorksLicenseBorrowingUIFigure);
            app.SpecifyBorrowPeriodPanel.Title = 'Specify Borrow Period';
            app.SpecifyBorrowPeriodPanel.Tag = 'borrowPeriodPanel';
            app.NumberofdaystoborrowSpinnerLabel = uilabel(app.SpecifyBorrowPeriodPanel);
            app.NumberofdaystoborrowSpinnerLabel.Text = 'Number of days to borrow';
            app.NumberofdaystoborrowSpinner = uispinner(app.SpecifyBorrowPeriodPanel);
            app.NumberofdaystoborrowSpinner.Limits = [1 180];
            app.NumberofdaystoborrowSpinner.RoundFractionalValues = 'on';
            app.NumberofdaystoborrowSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofdaystoborrowSpinnerValueChanged, true);
            app.NumberofdaystoborrowSpinner.Tag = 'daysSpinner';
            app.NumberofdaystoborrowSpinner.Value = 1;
            app.TurnOnAutoBorrowButton = uibutton(app.SpecifyBorrowPeriodPanel, 'push');
            app.TurnOnAutoBorrowButton.ButtonPushedFcn = createCallbackFcn(app, @TurnOnAutoBorrowButtonPushed, true);
            app.TurnOnAutoBorrowButton.Tag = 'onBorrowButton';
            app.TurnOnAutoBorrowButton.Visible = 'off';
            app.TurnOnAutoBorrowButton.Text = 'Turn On Auto Borrow';
            app.TurnOffAutoBorrowButton = uibutton(app.SpecifyBorrowPeriodPanel, 'push');
            app.TurnOffAutoBorrowButton.ButtonPushedFcn = createCallbackFcn(app, @TurnOffAutoBorrowButtonPushed, true);
            app.TurnOffAutoBorrowButton.Tag = 'offBorrowButton';
            app.TurnOffAutoBorrowButton.Enable = 'off';
            app.TurnOffAutoBorrowButton.Visible = 'off';
            app.TurnOffAutoBorrowButton.Text = 'Turn Off Auto Borrow';
            app.UntilendofLabel = uilabel(app.SpecifyBorrowPeriodPanel);
            app.UntilendofLabel.Tag = 'untilLabel';
            app.UntilendofLabel.Text = '(Until end of: )';
            app.CloseButton = uibutton(app.MathWorksLicenseBorrowingUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tag = 'closeButton';
            app.CloseButton.Text = 'Close';
            app.TextArea = uitextarea(app.MathWorksLicenseBorrowingUIFigure);
            app.TextArea.Tag = 'textMessageArea';
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea.Value = {'Automatic borrowing is turned on '; 'Continue to work and products will be borrowed automatically as you work with them. '; ''; 'You can close this window and borrowing will continue. '; ''; 'When you have borrowed all the product you need, click "Turn off Auto Borrow"  to display the status table. '};
            % Show the figure after all components are created
            app.MathWorksLicenseBorrowingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = borrowLicenses

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MathWorksLicenseBorrowingUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.MathWorksLicenseBorrowingUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MathWorksLicenseBorrowingUIFigure)
        end
    end
end