classdef InstrumentationVisualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InstrumentVisualizerUIFigure    matlab.ui.Figure
        VisualizePanel                  matlab.ui.container.Panel
        NameFilterEditField             matlab.ui.control.EditField
        NameFilterEditFieldLabel        matlab.ui.control.Label
        SortbyDropDown                  matlab.ui.control.DropDown
        SortbyDropDownLabel             matlab.ui.control.Label
        minDurationEditField            matlab.ui.control.NumericEditField
        minDurationEditFieldLabel       matlab.ui.control.Label
        ResettoDefaultButton            matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        PlotButton                      matlab.ui.control.Button
        ProfilerLabel                   matlab.ui.control.Label
        ClearDataButton                 matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        DataSourceDropDown              matlab.ui.control.DropDown
        DataSourceDropDownLabel         matlab.ui.control.Label
        InstrumentationVisualizerLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            instrumentationvisualizer.stopAndVisualize('sortBy', app.SortbyDropDown.Value,...
                                 'sortOrder', app.DropDown.Value,...
                                 'minDuration', app.minDurationEditField.Value,...
                                 'nameFilter', app.NameFilterEditField.Value);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if(strcmp(app.DataSourceDropDown.Value,"Recorder"))
                app.StartButton.Enable = false;
                instrumentationvisualizer.start;
                app.StopButton.Enable = true;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if(strcmp(app.DataSourceDropDown.Value,"Recorder"))
                app.StopButton.Enable = false;
                instrumentationvisualizer.stop;
                app.StartButton.Enable = true;
            end
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            instrumentationvisualizer.clearData;
        end

        % Button pushed function: ResettoDefaultButton
        function ResettoDefaultButtonPushed(app, event)
            app.minDurationEditField.Value = 0.001;
            app.SortbyDropDown.Value = "Start time";
            app.DropDown.Value = "Asc";
            app.NameFilterEditField.Value = "";
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InstrumentVisualizerUIFigure = uifigure('Visible', 'off');
            app.InstrumentVisualizerUIFigure.Name = 'Instrument Visualizer';
            app.InstrumentationVisualizerLabel = uilabel(app.InstrumentVisualizerUIFigure);
            app.InstrumentationVisualizerLabel.Text = 'Instrumentation Visualizer';
            app.DataSourceDropDownLabel = uilabel(app.InstrumentVisualizerUIFigure);
            app.DataSourceDropDownLabel.Text = 'Data Source';
            app.DataSourceDropDown = uidropdown(app.InstrumentVisualizerUIFigure);
            app.DataSourceDropDown.Items = {'Recorder'};
            app.DataSourceDropDown.Value = 'Recorder';
            app.StartButton = uibutton(app.InstrumentVisualizerUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.InstrumentVisualizerUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.ClearDataButton = uibutton(app.InstrumentVisualizerUIFigure, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.ProfilerLabel = uilabel(app.InstrumentVisualizerUIFigure);
            app.ProfilerLabel.Text = 'Profiler';
            app.VisualizePanel = uipanel(app.InstrumentVisualizerUIFigure);
            app.VisualizePanel.Title = 'Visualize';
            app.PlotButton = uibutton(app.VisualizePanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.DropDown = uidropdown(app.VisualizePanel);
            app.DropDown.Items = {'Asc', 'Desc'};
            app.DropDown.Value = 'Asc';
            app.ResettoDefaultButton = uibutton(app.VisualizePanel, 'push');
            app.ResettoDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoDefaultButtonPushed, true);
            app.ResettoDefaultButton.Text = 'Reset to Default';
            app.minDurationEditFieldLabel = uilabel(app.VisualizePanel);
            app.minDurationEditFieldLabel.Text = 'minDuration';
            app.minDurationEditField = uieditfield(app.VisualizePanel, 'numeric');
            app.minDurationEditField.Value = 0.001;
            app.SortbyDropDownLabel = uilabel(app.VisualizePanel);
            app.SortbyDropDownLabel.Text = 'Sort by';
            app.SortbyDropDown = uidropdown(app.VisualizePanel);
            app.SortbyDropDown.Items = {'Start time', 'Stop time', 'Duration', 'Source Name', 'Name'};
            app.SortbyDropDown.Value = 'Start time';
            app.NameFilterEditFieldLabel = uilabel(app.VisualizePanel);
            app.NameFilterEditFieldLabel.Text = 'Name Filter';
            app.NameFilterEditField = uieditfield(app.VisualizePanel, 'text');
            % Show the figure after all components are created
            app.InstrumentVisualizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InstrumentationVisualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InstrumentVisualizerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InstrumentVisualizerUIFigure)
        end
    end
end